classdef app2_trial < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RunAnalysisButton               matlab.ui.control.Button
        SaveAnalysisandSettingPanel     matlab.ui.container.Panel
        TextArea                        matlab.ui.control.TextArea
        PathtoSaveFolderEditField       matlab.ui.control.EditField
        PathtoSaveFolderEditFieldLabel  matlab.ui.control.Label
        SaveFullAnalysisButton          matlab.ui.control.Button
        SaveAnalysisSettingOnlyButton   matlab.ui.control.Button
        FramingControlsPanel            matlab.ui.container.Panel
        Label                           matlab.ui.control.Label
        WindowOverlapmsEditField        matlab.ui.control.NumericEditField
        WindowOverlapmsEditFieldLabel   matlab.ui.control.Label
        WindowLengthmsEditField         matlab.ui.control.NumericEditField
        WindowLengthmsEditFieldLabel    matlab.ui.control.Label
        WindowFunctionDropDown          matlab.ui.control.DropDown
        WindowFunctionDropDownLabel     matlab.ui.control.Label
        AnalysisTypePanel               matlab.ui.container.Panel
        DescriptorInformation           matlab.ui.control.TextArea
        ShowDescriptorInformationButton  matlab.ui.control.Button
        SpectralDescriptorDropDown      matlab.ui.control.DropDown
        SpectralDescriptorDropDownLabel  matlab.ui.control.Label
        SpectrumTypeDropDown            matlab.ui.control.DropDown
        SpectrumTypeDropDownLabel       matlab.ui.control.Label
        SignalandSamplingInformationPanel  matlab.ui.container.Panel
        PathtoTDMSFileEditField         matlab.ui.control.EditField
        PathtoTDMSFileEditFieldLabel    matlab.ui.control.Label
        SamplingRateHzEditField         matlab.ui.control.NumericEditField
        SamplingRateHzEditFieldLabel    matlab.ui.control.Label
        STFT                            matlab.ui.control.UIAxes
        TimeDomain                      matlab.ui.control.UIAxes
        FullFreq                        matlab.ui.control.UIAxes
        LowFreq                         matlab.ui.control.UIAxes
        MidFreq                         matlab.ui.control.UIAxes
        HighFreq                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        win
        overlap
        fftLength
        fullRange 
        bandStep
        maxOfRange
        lowRange
        midRange
        highRange
        samplingRate
        p_ref = 20e-6;
        time_tdms
        data_soundPa
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunAnalysisButton
        function RunAnalysisButtonPushed(app, event)
            progDlg = uiprogressdlg(app.UIFigure, ...
                'Title', 'Processing Data', ...
                'Message', 'Processing Data...', ...
                'Indeterminate', 'on', ...
                'Cancelable', 'off');

            app.UIFigure.Pointer = 'watch';
            app.RunAnalysisButton.Enable = 'off';        % Prevent double clicks
            app.RunAnalysisButton.Text = 'Processing...'; % Visual feedback
            app.RunAnalysisButton.BackgroundColor = [0.8 0.5 0.2]; % Orange accent

            % Force the UI to refresh immediately before the heavy computation starts!
            drawnow;
%% File Information
            file_name = app.PathtoTDMSFileEditField.Value;
            

%% Framing and Analysis Information
            app.SaveAnalysisSettingOnlyButtonPushed;

%% Pulling information from TDMS File
            info = tdmsinfo(file_name);
            ChannelGroupName_tdms = info.ChannelList.ChannelGroupName(1);
            ChannelName_tdms = info.ChannelList.ChannelName(1);
            NumSamples_tdms = double(info.ChannelList.NumSamples(1)); % add the double so it doesnt force calculations to int later
            
            data = tdmsread(file_name, ChannelGroupName=ChannelGroupName_tdms, ChannelNames=ChannelName_tdms, SampleRate=app.samplingRate);
            
            data_timetable = cell2mat(data);
            app.data_soundPa = table2array(data_timetable);
            app.time_tdms = data_timetable.Time;
            app.time_tdms = seconds(app.time_tdms);

%% DC offset and frequency vector

            app.data_soundPa = app.data_soundPa - mean(app.data_soundPa); % accounting for DC offset
            
            k=0:floor(NumSamples_tdms/2); % frequency bins, starts at 0 so the length is N/2+1
            frequency_spacing = app.samplingRate/NumSamples_tdms; % spacing for each bin
            freq = k*frequency_spacing; % converting bin number to frequency
            
%% Time-Domain Plot
           
            plot(app.TimeDomain, app.time_tdms, app.data_soundPa)
            title(app.TimeDomain, 'Time-Domain Signal');
            xlabel(app.TimeDomain, 'Time (s)');
            ylabel(app. TimeDomain, 'Sound Pressure (Pa)');
            xlim(app.TimeDomain, [0, app.time_tdms(end)]);

%% STFT Plot
            
            switch app.SpectrumTypeDropDown.Value
                case '1' 
                    spectrumType = 'magnitude';
                    spectrumRef = app.p_ref;
                    specTitle = 'Mag Spec';

                    [s,f,t] = stft(app.data_soundPa,app.samplingRate, 'Window',app.win,'OverlapLength',app.overlap,'FFTLength',app.fftLength, 'FrequencyRange','onesided');
                    
                    magPaPeak = abs(s);
                    
                    %adjustment for removal of negative half
                    if mod(app.fftLength, 2) == 0 % iff fftLength is even
                        magPaPeak(2:end-1, :) = 2*magPaPeak(2:end-1, :);
                    else % if fftLength is odd
                        magPaPeak(2:end, :) = 2*magPaPeak(2:end, :); 
                    end
                    
                    % adjusting coherent gain of window
                    magPaPeakNorm = magPaPeak/sum(app.win);

                    %pa_peak to pa_rms
                    magPaRMS = magPaPeakNorm/sqrt(2);

                    %pa_rms to db
                    magDB = mag2db(magPaRMS/spectrumRef);
                    
                    cla(app.STFT);
                    imagesc(app.STFT, t, f, magDB)
                    axis(app.STFT, 'xy')
                    xlabel(app.STFT, 'Time (s)')
                    ylabel(app.STFT, 'Frequency (Hz)')
                    cb = colorbar(app.STFT);
                    cb.Label.String = 'Magnitude (dB)';
                    xlim(app.STFT, [0, t(end)]);
                    ylim(app.STFT, [0, app.maxOfRange]);
                    drawnow limitrate;
                   
                case '2'
                    spectrumType = 'power';
                    spectrumRef = app.p_ref^2;
                    specTitle = 'Pow Spec';

                    [s,f,t] = stft(app.data_soundPa,app.samplingRate, 'Window',app.win,'OverlapLength',app.overlap,'FFTLength',app.fftLength, 'FrequencyRange','onesided');
                    
                    PowerPa = abs(s).^2;
                    
                    %adjustment for removal of negative half
                    if mod(app.fftLength, 2) == 0 % if fftLength is even
                        PowerPa(2:end-1, :) = 2*PowerPa(2:end-1, :);
                    else % if fftLength is odd
                        PowerPa(2:end, :) = 2*PowerPa(2:end, :); 
                    end
                    
                    % adjusting coherent gain of window
                    PowerPaNorm = PowerPa/sum(abs(app.win).^2);


                    %pa_rms to db
                    PowerDB = pow2db(PowerPaNorm/spectrumRef);
                    
                    cla(app.STFT);
                    imagesc(app.STFT, t, f, PowerDB)
                    axis(app.STFT, 'xy')
                    xlabel(app.STFT, 'Time (s)')
                    ylabel(app.STFT, 'Frequency (Hz)')
                    cb = colorbar(app.STFT);
                    cb.Label.String = 'Power (dB)';
                    xlim(app.STFT, [0, t(end)]);
                    ylim(app.STFT, [0, app.maxOfRange]);
                    drawnow limitrate;
                    

                case '3'
                    spectrumType = 'power';
                    spectrumRef = app.p_ref^2;
                    specTitle = 'Pow Spec';

                    [s,f,t] = stft(app.data_soundPa,app.samplingRate, 'Window',app.win,'OverlapLength',app.overlap,'FFTLength',app.fftLength, 'FrequencyRange','onesided');
                    
                    PSDPa = abs(s).^2;
                    
                    %adjustment for removal of negative half
                    if mod(app.fftLength, 2) == 0 % if fftLength is even
                        PSDPa(2:end-1, :) = 2*PSDPa(2:end-1, :);
                    else % if fftLength is odd
                        PSDPa(2:end, :) = 2*PSDPa(2:end, :); 
                    end
                    
                    % adjusting coherent gain of window and sampling freq
                    PSDPaNorm = PSDPa/(app.samplingRate*sum(abs(app.win).^2));

                    %pa_rms to db
                    PSDDB = pow2db(PSDPaNorm/spectrumRef);
                    
                    cla(app.STFT);
                    imagesc(app.STFT, t, f, PSDDB)
                    axis(app.STFT, 'xy')
                    xlabel(app.STFT, 'Time (s)')
                    ylabel(app.STFT, 'Frequency (Hz)')
                    cb = colorbar(app.STFT);
                    cb.Label.String = 'PSD (dB/Hz)';
                    xlim(app.STFT, [0, t(end)]);
                    ylim(app.STFT, [0, app.maxOfRange]);
                    drawnow limitrate;
                    

                otherwise
                    %

                    
            end   
            
            
%% Spectral Descriptor Plots
            switch app.SpectralDescriptorDropDown.Value
                case '1'
                    
                    %% centroid
                    %full
                    fullCentroid = spectralCentroid(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.fullRange, SpectrumType=spectrumType);
                    t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(fullCentroid,1));
                    plot(app.FullFreq, t, fullCentroid)
                    title(app.FullFreq, "Centroid - Full Frequency Band " + app.fullRange(1) + " to " + app.fullRange(2) + "Hz - " + specTitle)
                    xlabel(app.FullFreq, 'Time (s)')
                    ylabel(app.FullFreq, 'Hertz (Hz)')
                    xlim(app.FullFreq, [0, t(end)]);
                    %low
                    lowCentroid = spectralCentroid(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.lowRange, SpectrumType=spectrumType);
                    t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(lowCentroid,1));
                    plot(app.LowFreq, t, lowCentroid)
                    title(app.LowFreq, "Centroid - Low Frequency Band " + app.lowRange(1) + " to " + app.lowRange(2) + "Hz - " + specTitle)
                    xlabel(app.LowFreq, 'Time (s)')
                    ylabel(app.LowFreq, 'Hertz (Hz)')
                    xlim(app.LowFreq, [0, t(end)]);
                    %mid
                    midCentroid = spectralCentroid(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.midRange, SpectrumType=spectrumType);
                    t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(midCentroid,1));
                    plot(app.MidFreq, t, midCentroid)
                    title(app.MidFreq, "Centroid - Mid Frequency Band " + app.midRange(1) + " to " + app.midRange(2) + "Hz - " + specTitle)
                    xlabel(app.MidFreq, 'Time (s)')
                    ylabel(app.MidFreq, 'Hertz (Hz)')
                    xlim(app.MidFreq, [0, t(end)]);
                    %high
                    highCentroid = spectralCentroid(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.highRange, SpectrumType=spectrumType);
                    t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(highCentroid,1));
                    plot(app.HighFreq, t, highCentroid)
                    title(app.HighFreq, "Centroid - High Frequency Band " + app.highRange(1) + " to " + app.highRange(2) + "Hz - " + specTitle)
                    xlabel(app.HighFreq, 'Time (s)')
                    ylabel(app.HighFreq, 'Hertz (Hz)')
                    xlim(app.HighFreq, [0, t(end)]);
                    app.ShowDescriptorInformationButtonPushed;
                 

                case '2'
                    
                    %% spread
                    %full
                    fullSpread = spectralSpread(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.fullRange, SpectrumType=spectrumType);
                    t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(fullSpread,1));
                    plot(app.FullFreq, t, fullSpread)
                    title(app.FullFreq, "Spread - Full Frequency Band " + app.fullRange(1) + " to " + app.fullRange(2) + "Hz - " + specTitle)
                    xlabel(app.FullFreq, 'Time (s)')
                    ylabel(app.FullFreq, 'Hertz (Hz)')
                    xlim(app.FullFreq, [0, t(end)]);
                    %low
                    lowSpread = spectralSpread(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.lowRange, SpectrumType=spectrumType);
                    t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(lowSpread,1));
                    plot(app.LowFreq, t, lowSpread)
                    title(app.LowFreq, "Spread - Low Frequency Band " + app.lowRange(1) + " to " + app.lowRange(2) + "Hz - " + specTitle)
                    xlabel(app.LowFreq, 'Time (s)')
                    ylabel(app.LowFreq, 'Hertz (Hz)')
                    xlim(app.LowFreq, [0, t(end)]);
                    %mid
                    midSpread = spectralSpread(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.midRange, SpectrumType=spectrumType);
                    t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(midSpread,1));
                    plot(app.MidFreq, t, midSpread)
                    title(app.MidFreq, "Spread - Mid Frequency Band " + app.midRange(1) + " to " + app.midRange(2) + "Hz - " + specTitle)
                    xlabel(app.MidFreq, 'Time (s)')
                    ylabel(app.MidFreq, 'Hertz (Hz)')
                    xlim(app.MidFreq, [0, t(end)]);
                    %high
                    highSpread = spectralSpread(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.highRange, SpectrumType=spectrumType);
                    t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(highSpread,1));
                    plot(app.HighFreq, t, highSpread)
                    title(app.HighFreq, "Spread - High Frequency Band " + app.highRange(1) + " to " + app.highRange(2) + "Hz - " + specTitle)
                    xlabel(app.HighFreq, 'Time (s)')
                    ylabel(app.HighFreq, 'Hertz (Hz)')
                    xlim(app.HighFreq, [0, t(end)]);
                    app.ShowDescriptorInformationButtonPushed;

                case '3'
                    
                    %% skewness
                    %full
                    fullSkewness = spectralSkewness(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.fullRange, SpectrumType=spectrumType);
                    t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(fullSkewness,1));
                    plot(app.FullFreq, t, fullSkewness)
                    title(app.FullFreq, "Skewness - Full Frequency Band " + app.fullRange(1) + " to " + app.fullRange(2) + "Hz - " + specTitle)
                    xlabel(app.FullFreq, 'Time (s)')
                    ylabel(app.FullFreq, 'Unitless')
                    xlim(app.FullFreq, [0, t(end)]);
                    %low
                    lowSkewness = spectralSkewness(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.lowRange, SpectrumType=spectrumType);
                    t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(lowSkewness,1));
                    plot(app.LowFreq, t, lowSkewness)
                    title(app.LowFreq, "Skewness - Low Frequency Band " + app.lowRange(1) + " to " + app.lowRange(2) + "Hz - " + specTitle)
                    xlabel(app.LowFreq, 'Time (s)')
                    ylabel(app.LowFreq, 'Unitless')
                    xlim(app.LowFreq, [0, t(end)]);
                    %mid
                    midSkewness = spectralSkewness(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.midRange, SpectrumType=spectrumType);
                    t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(midSkewness,1));
                    plot(app.MidFreq, t, midSkewness)
                    title(app.MidFreq, "Skewness - Mid Frequency Band " + app.midRange(1) + " to " + app.midRange(2) + "Hz - " + specTitle)
                    xlabel(app.MidFreq, 'Time (s)')
                    ylabel(app.MidFreq, 'Unitless')
                    xlim(app.MidFreq, [0, t(end)]);
                    %high
                    highSkewness = spectralSkewness(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.highRange, SpectrumType=spectrumType);
                    t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(highSkewness,1));
                    plot(app.HighFreq, t, highSkewness)
                    title(app.HighFreq, "Skewness - High Frequency Band " + app.highRange(1) + " to " + app.highRange(2) + "Hz - " + specTitle)
                    xlabel(app.HighFreq, 'Time (s)')
                    ylabel(app.HighFreq, 'Unitless')
                    xlim(app.HighFreq, [0, t(end)]);
                    app.ShowDescriptorInformationButtonPushed;

                case '4'
                    
                    %% kurtosis
                    %full
                    fullKurtosis = spectralKurtosis(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.fullRange, SpectrumType=spectrumType);
                    t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(fullKurtosis,1));
                    plot(app.FullFreq, t, fullKurtosis)
                    title(app.FullFreq, "Kurtosis - Full Frequency Band " + app.fullRange(1) + " to " + app.fullRange(2) + "Hz - " + specTitle)
                    xlabel(app.FullFreq, 'Time (s)')
                    ylabel(app.FullFreq, 'Unitless')
                    xlim(app.FullFreq, [0, t(end)]);
                    %low
                    lowKurtosis = spectralKurtosis(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.lowRange, SpectrumType=spectrumType);
                    t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(lowKurtosis,1));
                    plot(app.LowFreq, t, lowKurtosis)
                    title(app.LowFreq, "Kurtosis - Low Frequency Band " + app.lowRange(1) + " to " + app.lowRange(2) + "Hz - " + specTitle)
                    xlabel(app.LowFreq, 'Time (s)')
                    ylabel(app.LowFreq, 'Unitless')
                    xlim(app.LowFreq, [0, t(end)]);
                    %mid
                    midKurtosis = spectralKurtosis(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.midRange, SpectrumType=spectrumType);
                    t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(midKurtosis,1));
                    plot(app.MidFreq, t, midKurtosis)
                    title(app.MidFreq, "Kurtosis - Mid Frequency Band " + app.midRange(1) + " to " + app.midRange(2) + "Hz - " + specTitle)
                    xlabel(app.MidFreq, 'Time (s)')
                    ylabel(app.MidFreq, 'Unitless')
                    xlim(app.MidFreq, [0, t(end)]);
                    %high
                    highKurtosis = spectralKurtosis(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.highRange, SpectrumType=spectrumType);
                    t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(highKurtosis,1));
                    plot(app.HighFreq, t, highKurtosis)
                    title(app.HighFreq, "Kurtosis - High Frequency Band " + app.highRange(1) + " to " + app.highRange(2) + "Hz - " + specTitle)
                    xlabel(app.HighFreq, 'Time (s)')
                    ylabel(app.HighFreq, 'Unitless')
                    xlim(app.HighFreq, [0, t(end)]);
                    app.ShowDescriptorInformationButtonPushed;

                case '5'
                    
                    %% entropy
                    %full
                    fullEntropy = spectralEntropy(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.fullRange, SpectrumType=spectrumType);
                    t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(fullEntropy,1));
                    plot(app.FullFreq, t, fullEntropy)
                    title(app.FullFreq, "Entropy - Full Frequency Band " + app.fullRange(1) + " to " + app.fullRange(2) + "Hz - " + specTitle)
                    xlabel(app.FullFreq, 'Time (s)')
                    ylabel(app.FullFreq, 'Nats')
                    xlim(app.FullFreq, [0, t(end)]);
                    %low
                    lowEntropy = spectralEntropy(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.lowRange, SpectrumType=spectrumType);
                    t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(lowEntropy,1));
                    plot(app.LowFreq, t, lowEntropy)
                    title(app.LowFreq, "Entropy - Low Frequency Band " + app.lowRange(1) + " to " + app.lowRange(2) + "Hz - " + specTitle)
                    xlabel(app.LowFreq, 'Time (s)')
                    ylabel(app.LowFreq, 'Nats')
                    xlim(app.LowFreq, [0, t(end)]);
                    %mid
                    midEntropy = spectralEntropy(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.midRange, SpectrumType=spectrumType);
                    t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(midEntropy,1));
                    plot(app.MidFreq, t, midEntropy)
                    title(app.MidFreq, "Entropy - Mid Frequency Band " + app.midRange(1) + " to " + app.midRange(2) + "Hz - " + specTitle)
                    xlabel(app.MidFreq, 'Time (s)')
                    ylabel(app.MidFreq, 'Nats')
                    xlim(app.MidFreq, [0, t(end)]);
                    %high
                    highEntropy = spectralEntropy(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.highRange, SpectrumType=spectrumType);
                    t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(highEntropy,1));
                    plot(app.HighFreq, t, highEntropy)
                    title(app.HighFreq, "Entropy - High Frequency Band " + app.highRange(1) + " to " + app.highRange(2) + "Hz - " + specTitle)
                    xlabel(app.HighFreq, 'Time (s)')
                    ylabel(app.HighFreq, 'Nats')
                    xlim(app.HighFreq, [0, t(end)]);
                    app.ShowDescriptorInformationButtonPushed;

                case '6'
                    
                    %% flatness
                    %full
                    fullFlatness = spectralFlatness(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.fullRange, SpectrumType=spectrumType);
                    t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(fullFlatness,1));
                    plot(app.FullFreq, t, fullFlatness)
                    title(app.FullFreq, "Flatness - Full Frequency Band " + app.fullRange(1) + " to " + app.fullRange(2) + "Hz - " + specTitle)
                    xlabel(app.FullFreq, 'Time (s)')
                    ylabel(app.FullFreq, 'Unitless')
                    xlim(app.FullFreq, [0, t(end)]);
                    %low
                    lowFlatness = spectralFlatness(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.lowRange, SpectrumType=spectrumType);
                    t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(lowFlatness,1));
                    plot(app.LowFreq, t, lowFlatness)
                    title(app.LowFreq, "Flatness - Low Frequency Band " + app.lowRange(1) + " to " + app.lowRange(2) + "Hz - " + specTitle)
                    xlabel(app.LowFreq, 'Time (s)')
                    ylabel(app.LowFreq, 'Unitless')
                    xlim(app.LowFreq, [0, t(end)]);
                    %mid
                    midFlatness = spectralFlatness(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.midRange, SpectrumType=spectrumType);
                    t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(midFlatness,1));
                    plot(app.MidFreq, t, midFlatness)
                    title(app.MidFreq, "Flatness - Mid Frequency Band " + app.midRange(1) + " to " + app.midRange(2) + "Hz - " + specTitle)
                    xlabel(app.MidFreq, 'Time (s)')
                    ylabel(app.MidFreq, 'Unitless')
                    xlim(app.MidFreq, [0, t(end)]);
                    %high
                    highFlatness = spectralFlatness(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.highRange, SpectrumType=spectrumType);
                    t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(highFlatness,1));
                    plot(app.HighFreq, t, highFlatness)
                    title(app.HighFreq, "Flatness - High Frequency Band " + app.highRange(1) + " to " + app.highRange(2) + "Hz - " + specTitle)
                    xlabel(app.HighFreq, 'Time (s)')
                    ylabel(app.HighFreq, 'Unitless')
                    xlim(app.HighFreq, [0, t(end)]);
                    app.ShowDescriptorInformationButtonPushed;

                case '7'
                    
                    %% crest
                    %full
                    fullCrest = spectralCrest(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.fullRange, SpectrumType=spectrumType);
                    t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(fullCrest,1));
                    plot(app.FullFreq, t, fullCrest)
                    title(app.FullFreq, "Crest - Full Frequency Band " + app.fullRange(1) + " to " + app.fullRange(2) + "Hz - " + specTitle)
                    xlabel(app.FullFreq, 'Time (s)')
                    ylabel(app.FullFreq, 'Unitless')
                    xlim(app.FullFreq, [0, t(end)]);
                    %low
                    lowCrest = spectralCrest(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.lowRange, SpectrumType=spectrumType);
                    t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(lowCrest,1));
                    plot(app.LowFreq, t, lowCrest)
                    title(app.LowFreq, "Crest - Low Frequency Band " + app.lowRange(1) + " to " + app.lowRange(2) + "Hz - " + specTitle)
                    xlabel(app.LowFreq, 'Time (s)')
                    ylabel(app.LowFreq, 'Unitless')
                    xlim(app.LowFreq, [0, t(end)]);
                    %mid
                    midCrest = spectralCrest(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.midRange, SpectrumType=spectrumType);
                    t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(midCrest,1));
                    plot(app.MidFreq, t, midCrest)
                    title(app.MidFreq, "Crest - Mid Frequency Band " + app.midRange(1) + " to " + app.midRange(2) + "Hz - " + specTitle)
                    xlabel(app.MidFreq, 'Time (s)')
                    ylabel(app.MidFreq, 'Unitless')
                    xlim(app.MidFreq, [0, t(end)]);
                    %high
                    highCrest = spectralCrest(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.highRange, SpectrumType=spectrumType);
                    t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(highCrest,1));
                    plot(app.HighFreq, t, highCrest)
                    title(app.HighFreq, "Crest - High Frequency Band " + app.highRange(1) + " to " + app.highRange(2) + "Hz - " + specTitle)
                    xlabel(app.HighFreq, 'Time (s)')
                    ylabel(app.HighFreq, 'Unitless')
                    xlim(app.HighFreq, [0, t(end)]);
                    app.ShowDescriptorInformationButtonPushed;

                case '8'
                    
                    %% flux
                    %full
                    fullFlux = spectralFlux(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.fullRange, SpectrumType=spectrumType);
                    t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(fullFlux,1));
                    plot(app.FullFreq, t, fullFlux)
                    title(app.FullFreq, "Flux - Full Frequency Band " + app.fullRange(1) + " to " + app.fullRange(2) + "Hz - " + specTitle)
                    xlabel(app.FullFreq, 'Time (s)')
                    ylabel(app.FullFreq, 'Unitless')
                    xlim(app.FullFreq, [0, t(end)]);
                    %low
                    lowFlux = spectralFlux(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.lowRange, SpectrumType=spectrumType);
                    t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(lowFlux,1));
                    plot(app.LowFreq, t, lowFlux)
                    title(app.LowFreq, "Flux - Low Frequency Band " + app.lowRange(1) + " to " + app.lowRange(2) + "Hz - " + specTitle)
                    xlabel(app.LowFreq, 'Time (s)')
                    ylabel(app.LowFreq, 'Unitless')
                    xlim(app.LowFreq, [0, t(end)]);
                    %mid
                    midFlux = spectralFlux(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.midRange, SpectrumType=spectrumType);
                    t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(midFlux,1));
                    plot(app.MidFreq, t, midFlux)
                    title(app.MidFreq, "Flux - Mid Frequency Band " + app.midRange(1) + " to " + app.midRange(2) + "Hz - " + specTitle)
                    xlabel(app.MidFreq, 'Time (s)')
                    ylabel(app.MidFreq, 'Unitless')
                    xlim(app.MidFreq, [0, t(end)]);
                    %high
                    highFlux = spectralFlux(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.highRange, SpectrumType=spectrumType);
                    t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(highFlux,1));
                    plot(app.HighFreq, t, highFlux)
                    title(app.HighFreq, "Flux - High Frequency Band " + app.highRange(1) + " to " + app.highRange(2) + "Hz - " + specTitle)
                    xlabel(app.HighFreq, 'Time (s)')
                    ylabel(app.HighFreq, 'Unitless')
                    xlim(app.HighFreq, [0, t(end)]);
                    app.ShowDescriptorInformationButtonPushed;

                case '9'
                    
                    %% slope
                    %full
                    fullSlope = spectralSlope(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.fullRange, SpectrumType=spectrumType);
                    t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(fullSlope,1));
                    plot(app.FullFreq, t, fullSlope)
                    title(app.FullFreq, "Slope - Full Frequency Band " + app.fullRange(1) + " to " + app.fullRange(2) + "Hz - " + specTitle)
                    xlabel(app.FullFreq, 'Time (s)')
                    ylabel(app.FullFreq, 'Decibel/Hertz (dB/Hz)')
                    xlim(app.FullFreq, [0, t(end)]);
                    %low
                    lowSlope = spectralSlope(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.lowRange, SpectrumType=spectrumType);
                    t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(lowSlope,1));
                    plot(app.LowFreq, t, lowSlope)
                    title(app.LowFreq, "Slope - Low Frequency Band " + app.lowRange(1) + " to " + app.lowRange(2) + "Hz - " + specTitle)
                    xlabel(app.LowFreq, 'Time (s)')
                    ylabel(app.LowFreq, 'Decibel/Hertz (dB/Hz)')
                    xlim(app.LowFreq, [0, t(end)]);
                    %mid
                    midSlope = spectralSlope(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.midRange, SpectrumType=spectrumType);
                    t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(midSlope,1));
                    plot(app.MidFreq, t, midSlope)
                    title(app.MidFreq, "Slope - Mid Frequency Band " + app.midRange(1) + " to " + app.midRange(2) + "Hz - " + specTitle)
                    xlabel(app.MidFreq, 'Time (s)')
                    ylabel(app.MidFreq, 'Decibel/Hertz (dB/Hz)')
                    xlim(app.MidFreq, [0, t(end)]);
                    %high
                    highSlope = spectralSlope(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.highRange, SpectrumType=spectrumType);
                    t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(highSlope,1));
                    plot(app.HighFreq, t, highSlope)
                    title(app.HighFreq, "Slope - High Frequency Band " + app.highRange(1) + " to " + app.highRange(2) + "Hz - " + specTitle)
                    xlabel(app.HighFreq, 'Time (s)')
                    ylabel(app.HighFreq, 'Decibel/Hertz (dB/Hz)')
                    xlim(app.HighFreq, [0, t(end)]);
                    app.ShowDescriptorInformationButtonPushed;

                case '10'
                    
                    %% decrease
                    %full
                    fullDecrease = spectralDecrease(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.fullRange, SpectrumType=spectrumType);
                    t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(fullDecrease,1));
                    plot(app.FullFreq, t, fullDecrease)
                    title(app.FullFreq, "Decrease - Full Frequency Band " + app.fullRange(1) + " to " + app.fullRange(2) + "Hz - " + specTitle)
                    xlabel(app.FullFreq, 'Time (s)')
                    ylabel(app.FullFreq, 'Unitless')
                    xlim(app.FullFreq, [0, t(end)]);
                    %low
                    lowDecrease = spectralDecrease(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.lowRange, SpectrumType=spectrumType);
                    t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(lowDecrease,1));
                    plot(app.LowFreq, t, lowDecrease)
                    title(app.LowFreq, "Decrease - Low Frequency Band " + app.lowRange(1) + " to " + app.lowRange(2) + "Hz - " + specTitle)
                    xlabel(app.LowFreq, 'Time (s)')
                    ylabel(app.LowFreq, 'Unitless')
                    xlim(app.LowFreq, [0, t(end)]);
                    %mid
                    midDecrease = spectralDecrease(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.midRange, SpectrumType=spectrumType);
                    t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(midDecrease,1));
                    plot(app.MidFreq, t, midDecrease)
                    title(app.MidFreq, "Decrease - Mid Frequency Band " + app.midRange(1) + " to " + app.midRange(2) + "Hz - " + specTitle)
                    xlabel(app.MidFreq, 'Time (s)')
                    ylabel(app.MidFreq, 'Unitless')
                    xlim(app.MidFreq, [0, t(end)]);
                    %high
                    highDecrease = spectralDecrease(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.highRange, SpectrumType=spectrumType);
                    t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(highDecrease,1));
                    plot(app.HighFreq, t, highDecrease)
                    title(app.HighFreq, "Decrease - High Frequency Band " + app.highRange(1) + " to " + app.highRange(2) + "Hz - " + specTitle)
                    xlabel(app.HighFreq, 'Time (s)')
                    ylabel(app.HighFreq, 'Unitless')
                    xlim(app.HighFreq, [0, t(end)]);
                    app.ShowDescriptorInformationButtonPushed;
                    
                case '11'
                    
                    %% rolloff point
                    %full
                    fullRolloffPoint = spectralRolloffPoint(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.fullRange, SpectrumType=spectrumType);
                    t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(fullRolloffPoint,1));
                    plot(app.FullFreq, t, fullRolloffPoint)
                    title(app.FullFreq, "Rolloff Point - Full Frequency Band " + app.fullRange(1) + " to " + app.fullRange(2) + "Hz - " + specTitle)
                    xlabel(app.FullFreq, 'Time (s)')
                    ylabel(app.FullFreq, 'Hertz (Hz)')
                    xlim(app.FullFreq, [0, t(end)]);
                    %low
                    lowRolloffPoint = spectralRolloffPoint(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.lowRange, SpectrumType=spectrumType);
                    t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(lowRolloffPoint,1));
                    plot(app.LowFreq, t, lowRolloffPoint)
                    title(app.LowFreq, "Rolloff Point - Low Frequency Band " + app.lowRange(1) + " to " + app.lowRange(2) + "Hz - " + specTitle)
                    xlabel(app.LowFreq, 'Time (s)')
                    ylabel(app.LowFreq, 'Hertz (Hz)')
                    xlim(app.LowFreq, [0, t(end)]);
                    %mid
                    midRolloffPoint = spectralRolloffPoint(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.midRange, SpectrumType=spectrumType);
                    t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(midRolloffPoint,1));
                    plot(app.MidFreq, t, midRolloffPoint)
                    title(app.MidFreq, "Rolloff Point - Mid Frequency Band " + app.midRange(1) + " to " + app.midRange(2) + "Hz - " + specTitle)
                    xlabel(app.MidFreq, 'Time (s)')
                    ylabel(app.MidFreq, 'Hertz (Hz)')
                    xlim(app.MidFreq, [0, t(end)]);
                    %high
                    highRolloffPoint = spectralRolloffPoint(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.highRange, SpectrumType=spectrumType);
                    t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(highRolloffPoint,1));
                    plot(app.HighFreq, t, highRolloffPoint)
                    title(app.HighFreq, "Rolloff Point - High Frequency Band " + app.highRange(1) + " to " + app.highRange(2) + "Hz - " + specTitle)
                    xlabel(app.HighFreq, 'Time (s)')
                    ylabel(app.HighFreq, 'Hertz (Hz)')
                    xlim(app.HighFreq, [0, t(end)]);
                    app.ShowDescriptorInformationButtonPushed;
                    
                otherwise
                    %
            end
            
            app.UIFigure.Pointer = 'arrow';
            app.RunAnalysisButton.Enable = 'on';
            app.RunAnalysisButton.Text = 'Run Analysis';
            app.RunAnalysisButton.BackgroundColor = [0.2314 0.6667 0.1961]; % Original green

        end

        % Button pushed function: ShowDescriptorInformationButton
        function ShowDescriptorInformationButtonPushed(app, event)
            switch app.SpectralDescriptorDropDown.Value
                case '1'
                    
                    %centroid
                    app.DescriptorInformation.Value = sprintf(['What It Measures:\nThe weighted average frequency ("center of mass") of the sound spectrum.' ...
                        '\n\nInterpretation:\nHigher values mean strong high frequency or long high-frequency tails are pulling the centroid up. Unit Hz.' ...
                        'Lower values mean the spectrum is low frequency heavy. Wide high frequency spiked can pull the centroid up even if low-frequency energy is dominant\n' ...
                        '\nFraming Effects:\nShorter windows track rapid, time-dependent pitch shifts and transient spiked. Longer windows smooth out the graph while keeping the overll contour, ' ...
                        'but they can hide brief, time-critical frequency changes']);

                case '2'
                    
                    %spread
                    app.DescriptorInformation.Value = sprintf(['What It Measures:\nThe spectral bandwidth or average distance of energy distribution away from the centroid. Unit Hz.' ...
                        '\n\nInterpretation:\nLow values mean energy is tightly clusted around centroid. High values mean energy is broadly disperesed across frequencies.' ...
                        '\n\nFraming Effects:\nShorter windows cause the spread to fluctuate. Longer windows stabalize the measurement but oversmoothing can smear distinct peaks.']);

                case '3'
                    
                    %skewness
                    app.DescriptorInformation.Value = sprintf(['What It Measures:\nThe directional asymmetry of energy around the spectral centroid. Unitless.' ...
                        '\n\nInterpretation:\nPositive values (>0) mean energy is concentrated at lower frequencies with a long tail into the high frequencies.' ...
                        'Negative values (<0) mean energy is pushed into the higher frequencies with a long tail into the low frequencies. Near zero is a balanced symmetric distribution of frequencies.' ...
                        '\n\nFraming Effects:\nShorter windows bring out briefs shits in low-vs-high frequency balance. Longer windows smooth out temporary energy tilts to show the overal average balance.']);

                case '4'
                    
                    %kurtosis
                    app.DescriptorInformation.Value = sprintf(['What It Measures:\nThe "spikiness" of the spectrum relative to a Gaussian distribution. Unitless.' ...
                        '\n\nInterpretation:\nHigh values indicate strong, isolated tonal peaks or sharp resonance. ' ...
                        'Low values mean the spectrum is flat, featureless, or uniformly noisy, with no single peak dominating.' ...
                        '\n\nFraming Effects:\n Shorter windows can artificially reduce kurtosis if spectral leakage smears tall peaks across adjacent frequency values. ' ...
                        'Longer windows give finer frequency resolution to resolve shark, narrow peaks, but can wash out brief changes.']);

                case '5'
                    
                    %entropy
                    app.DescriptorInformation.Value = sprintf(['What It Measures:\n The degree of randomness, disorder, or complexity in the spectral energy distribution. Unit Nats' ...
                        '\n\nInterpretation:\nLow values mean the energy is organized in predictable, marrow bands or simple tones. High values mean energy is randomly or complexly distributed across many frequencies.' ...
                        ' Introducing broad background noise rapidly elevates the entropy baseline.' ...
                        '\n\nFraming Effects:\nShorter windows pick up sudden, brief, shifts. Longer windows smooth the entropy curve into a stable baseline.']);

                case '6'
                    
                    %flatness
                    app.DescriptorInformation.Value = sprintf(['What It Measures:\nThe ratio of geomatric mean to arithmetic mean of the spectrum, measuring how "noise-like" vs. "tone-like the signal is. Unitless 0 to 1.' ...
                        '\n\nInterpretation:\nValues near zero mean energy is concentrated in a few sharp, isolated frequencies. Values near 1 mean energy is distributed evenly across all frequencies. ' ...
                        'A single dominant peak will drastically drop the overall flatness score.' ...
                        '\n\nFraming Effects:\nShorter windows can artificially elevate flatness because FFT resolution smooths distinct peaks. Longer windows improve peak separation, yield cleaner frops towars zero for tonal content.']);

                case '7'
                    
                    %crest
                    app.DescriptorInformation.Value = sprintf(['What It Measures:\nThe ratio of the maximum value of the spectrum to the arithmetic mean of the spectrum. Unitless.' ...
                        '\n\nInterpretation:\nHigh values mean a prominent, isolated tone. Low values mean the tallest peak is barely higher than the average baground level, indicating a diffuse or noisy specturm.' ...
                        '\n\nFraming Effects:\nShorter windows reduce frequency resolution, which can lower the measured peak height relative to the average. ' ...
                        'Longer windows sharpen individual peaks, pushing the crest high and smoothing out temporary spikes.']);

                case '8'
                    
                    %flux
                    app.DescriptorInformation.Value = sprintf(['What It Measures:\nThe frame-to-frame change in the power spectrum over time. Unitless.' ...
                        '\n\nInterpretation:\nSharp upward spikes mark the exact moment where the spectrum changes rapidly. Near-zero values indicate steady-state, stationary operation where consecutive spectral frames look identical. ' ...
                        'Flat, elevated, horisontal sections represent steady-state operation where the step height indicates the amount of continuous baseline noise or stationary energy present.' ...
                        '\n\nFraming Effects:\nHighly dependent on frame overlap. High overlap (75%+ provides high temporal tracking so momentary spikes arent missed. ' ...
                        'Low overlap or long windows smear the frame differences together, flattening flux peaks.']);

                case '9'
                    
                    %slope
                    app.DescriptorInformation.Value = sprintf(['What It Measures:\nThe overal linear drop-off rate of the spectrum as frequency increases. Units dB/Hz.' ...
                        '\n\nInterpretation:\nSteeper negative slopes indicate that energy is heavily concentrated in low frequencies and rolls off rapidly. Flatter, near zero slops mean energy remains high across upper frequencies.' ...
                        '\n\nFraming Effects:\nShorter windows can cause the slope to fluctuate wildly due to local peak variations. Longer windows smooth out spectral ripples for a stable strimate of the global spectral trend.']);

                case '10'
                    
                    %decrease
                    app.DescriptorInformation.Value = sprintf(['What It Measures:\nThe rate at which spectral energy drops off relative to the fundamental lowest frequency bin. Unitless.' ...
                        '\n\nInterpretation:\nHigh values mean energy is concentrated at the low end and falls off steeply toward higher frequencies. Low values mean high-frequency energy remains strong relative to the base frequency.' ...
                        '\n\nFraming Effects:\nShorter windows may fail to capture the true lowest frequecy accurately is frequency resolution is too low, causing the metric to jump around. ' ...
                        'Longer windows give a stable estimate of the low-to-high decay slope.']);
                    
                case '11'
                    
                    %rolloff point
                    app.DescriptorInformation.Value = sprintf(['What It Measures:\nThe frequency threshold bellow which set a percentage (95% here) of the total spectral power resides.' ...
                        '\n\nInterpretation:\nHigh values mean significant energy or long tails extend deep into the upper frequencies. Low values mean almost all power is contained in the lower frequencies.' ...
                        ' Sudden upward jumps point to high-frequency noise momentarily appearing.' ...
                        '\n\nFraming Effects:\nShorter windows with high overlap react immediately to short-duraction high-frequency bins. Longer windows smooth the threshold line over time, masking short-term spikes.']);
                    
                otherwise
                    %


            end
                                                     
        end

        % Button pushed function: SaveAnalysisSettingOnlyButton
        function SaveAnalysisSettingOnlyButtonPushed(app, event)
            app.samplingRate=app.SamplingRateHzEditField.Value;
            switch app.WindowFunctionDropDown.Value
                case 1
                    app.win = rectwin(round(app.samplingRate*app.WindowLengthmsEditField.Value/1000));
                    winFun = 'Rectangular';

                case 2
                    app.win = hann(round(app.samplingRate*app.WindowLengthmsEditField.Value/1000));
                    winFun = 'Hann';

                case 3
                    app.win = hamming(round(app.samplingRate*app.WindowLengthmsEditField.Value/1000));
                    winFun = 'Hamming';

                case 4
                    app.win = blackman(round(app.samplingRate*app.WindowLengthmsEditField.Value/1000));
                    winFun = 'Blackman';

                otherwise
                    %

            end

            app.overlap = round(app.samplingRate*app.WindowOverlapmsEditField.Value/1000);
            app.fftLength = numel(app.win);

            app.maxOfRange = app.samplingRate/2;
            app.fullRange = [0 app.maxOfRange];
            app.bandStep = app.maxOfRange/3;
            app.lowRange = [0 app.bandStep];
            app.midRange = [app.bandStep 2*(app.bandStep)];
            app.highRange = [2*(app.bandStep) app.maxOfRange];

            saveFolder = app.PathtoSaveFolderEditField.Value;
            filePath = fullfile(saveFolder, "freq_band_ranges"+".txt");
            savTable = array2table([app.fullRange',app.lowRange', app.midRange', app.highRange'], ...
                "VariableNames",{'Full Freq Band Range (Hz)', 'Low Freq Band Range (Hz)', 'Mid Freq Band Range (Hz)', 'High Freq Band Range (Hz)'});
            writetable(savTable, filePath, 'Delimiter', '\t');

            filePath = fullfile(saveFolder, "analysis_settings"+".txt");
            savTable = table(string(app.PathtoTDMSFileEditField.Value), app.samplingRate, string(winFun), app.WindowLengthmsEditField.Value, app.WindowOverlapmsEditField.Value);
           savTable.Properties.VariableNames = {'Data Path', 'Sampling Rate (Hz)', 'Window Function', 'Window Length (ms)', 'Window Overlap (ms)'};
            writetable(savTable, filePath, 'Delimiter', '\t');

        end

        % Button pushed function: SaveFullAnalysisButton
        function SaveFullAnalysisButtonPushed(app, event)
            
            app.RunAnalysisButtonPushed;
            app.SaveAnalysisSettingOnlyButtonPushed;
            saveFolder = app.PathtoSaveFolderEditField.Value;
            
            progDlg = uiprogressdlg(app.UIFigure, ...
                'Title', 'Saving Full Analysis', ...
                'Message', 'Saving Full Analysis...', ...
                'Indeterminate', 'on', ...
                'Cancelable', 'off');

            app.UIFigure.Pointer = 'watch';
            app.SaveFullAnalysisButton.Enable = 'off';        % Prevent double clicks
            app.SaveFullAnalysisButton.Text = 'Processing...'; % Visual feedback
            app.SaveFullAnalysisButton.BackgroundColor = [0.8 0.5 0.2]; % Orange accent

            % Force the UI to refresh immediately before the heavy computation starts!
            drawnow;
            figureNames = ["time_domain_signal", "STFTs", "centroid_magnitude", "spread_magnitude", "skewness_magnitude", "kurtosis_magnitude", "entropy_magnitude", "flatness_magnitude", "crest_magnitude", "flux_magnitude", "slope_magnitude", "decrease_magnitude", "rolloffpoint_magnitude", "centroid_power", "spread_power", "skewness_power", "kurtosis_power", "entropy_power", "flatness_power", "crest_power", "flux_power", "slope_power", "decrease_power", "rolloffpoint_power"];
            figList = gobjects(24,1);

            figList(1) = figure(1);

            plot(app.time_tdms, app.data_soundPa)
            title('Time-Domain Signal');
            xlabel('Time (s)');
            ylabel('Sound Pressure (Pa)');
            xlim([0, app.time_tdms(end)]);
            
            filePath = fullfile(saveFolder, figureNames(1)+".txt");
            savTable = array2table([app.time_tdms,app.data_soundPa],"VariableNames",{'Time (s)', 'Sound Pressure(Pa)'});
            writetable(savTable, filePath, 'Delimiter', '\t');
            
            figList(2) = figure(2);
            tiledlayout(3,1);

            spectrumRef = app.p_ref;

            [s,f,t] = stft(app.data_soundPa,app.samplingRate, 'Window',app.win,'OverlapLength',app.overlap,'FFTLength',app.fftLength, 'FrequencyRange','onesided');
            
            magPaPeak = abs(s);
            
            %adjustment for removal of negative half
            if mod(app.fftLength, 2) == 0 % if fftLength is even
                magPaPeak(2:end-1, :) = 2*magPaPeak(2:end-1, :);
            else % if fftLength is odd
                magPaPeak(2:end, :) = 2*magPaPeak(2:end, :); 
            end
            
            % adjusting coherent gain of window
            magPaPeakNorm = magPaPeak/sum(app.win);

            %pa_peak to pa_rms
            magPaRMS = magPaPeakNorm/sqrt(2);

            %pa_rms to db
            magDB = mag2db(magPaRMS/spectrumRef);
            
            nexttile;
            imagesc(t, f, magDB)
            axis('xy')
            xlabel('Time (s)')
            ylabel('Frequency (Hz)')
            cb = colorbar();
            cb.Label.String = 'Magnitude (dB)';
            xlim([0, t(end)]);
            ylim([0, app.maxOfRange]);
            
            filePath = fullfile(saveFolder, figureNames(2)+"_mag_"+".txt");
            disp(size(t))
            disp(size(f))
            disp(size(magDB))
            %savTable = array2table([t',f,magDB],"VariableNames",{'Time (s)', 'Frequency (Hz)', 'Magnitude (dB)'});
            %writetable(savTable, filePath, 'Delimiter', '\t');
            
            spectrumRef = app.p_ref^2;

            [s,f,t] = stft(app.data_soundPa,app.samplingRate, 'Window',app.win,'OverlapLength',app.overlap,'FFTLength',app.fftLength, 'FrequencyRange','onesided');
            
            PowerPa = abs(s).^2;
            
            %adjustment for removal of negative half
            if mod(app.fftLength, 2) == 0 % if fftLength is even
                PowerPa(2:end-1, :) = 2*PowerPa(2:end-1, :);
            else % if fftLength is odd
                PowerPa(2:end, :) = 2*PowerPa(2:end, :); 
            end
            
            % adjusting coherent gain of window
            PowerPaNorm = PowerPa/sum(abs(app.win).^2);


            %pa_rms to db
            PowerDB = pow2db(PowerPaNorm/spectrumRef);
            
            nexttile;
            imagesc(t, f, PowerDB)
            axis('xy')
            xlabel('Time (s)')
            ylabel('Frequency (Hz)')
            cb = colorbar();
            cb.Label.String = 'Power (dB)';
            xlim([0, t(end)]);
            ylim([0, app.maxOfRange]);

            filePath = fullfile(saveFolder, figureNames(2)+"_power_"+".txt");
            %savTable = array2table([t,f,magDB],"VariableNames",{'Time (s)', 'Frequency (Hz)', 'Power (dB)'});
            %writetable(savTable, filePath, 'Delimiter', '\t');


            spectrumRef = app.p_ref^2;

            [s,f,t] = stft(app.data_soundPa,app.samplingRate, 'Window',app.win,'OverlapLength',app.overlap,'FFTLength',app.fftLength, 'FrequencyRange','onesided');
            
            PSDPa = abs(s).^2;
            
            %adjustment for removal of negative half
            if mod(app.fftLength, 2) == 0 % if fftLength is even
                PSDPa(2:end-1, :) = 2*PSDPa(2:end-1, :);
            else % if fftLength is odd
                PSDPa(2:end, :) = 2*PSDPa(2:end, :); 
            end
            
            % adjusting coherent gain of window and sampling freq
            PSDPaNorm = PSDPa/(app.samplingRate*sum(abs(app.win).^2));

            %pa_rms to db
            PSDDB = pow2db(PSDPaNorm/spectrumRef);
            
            nexttile;
            imagesc(t, f, PSDDB)
            axis('xy')
            xlabel('Time (s)')
            ylabel('Frequency (Hz)')
            cb = colorbar();
            cb.Label.String = 'PSD (dB/Hz)';
            xlim([0, t(end)]);
            ylim([0, app.maxOfRange]);

            filePath = fullfile(saveFolder, figureNames(2)+"_psb_"+".txt");
            %savTable = array2table([t,f,magDB],"VariableNames",{'Time (s)', 'Frequency (Hz)', 'PSB (dB)'});
            %writetable(savTable, filePath, 'Delimiter', '\t');


            for i = 1:2 
                switch i 
                    case 1
                        spectrumType = 'magnitude';
                        specTitle = 'Magnitude Spectrum';
                        figNum = [3 4 5 6 7 8 9 10 11 12 13];
                    case 2
                        spectrumType = 'power';
                        specTitle = 'Power Spectrum';
                        figNum = [14 15 16 17 18 19 20 21 22 23 24];
                    otherwise
                        %
                end
                
                %% centroid
                figList(figNum(1)) = figure(figNum(1));
                tiledlayout(2,2);

                %full
                fullCentroid = spectralCentroid(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.fullRange, SpectrumType=spectrumType);
                t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(fullCentroid,1));
                nexttile;
                plot(t, fullCentroid)
                title("Centroid - Full Frequency Band " + app.fullRange(1) + " to " + app.fullRange(2) + "Hz - " + specTitle)
                xlabel('Time (s)')
                ylabel('Hertz (Hz)')
                xlim([0, t(end)]);

                filePath = fullfile(saveFolder, figureNames(figNum(1))+"_full_"+".txt");
                savTable = array2table([t',fullCentroid],"VariableNames",{'Time (s)', 'Frequency (Hz)'});
                writetable(savTable, filePath, 'Delimiter', '\t');

                %low
                lowCentroid = spectralCentroid(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.lowRange, SpectrumType=spectrumType);
                t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(lowCentroid,1));
                nexttile;
                plot(t, lowCentroid)
                title("Centroid - Low Frequency Band " + app.lowRange(1) + " to " + app.lowRange(2) + "Hz - " + specTitle)
                xlabel('Time (s)')
                ylabel('Hertz (Hz)')
                xlim([0, t(end)]);

                filePath = fullfile(saveFolder, figureNames(figNum(1))+"_low_"+".txt");
                savTable = array2table([t',lowCentroid],"VariableNames",{'Time (s)', 'Frequency (Hz)'});
                writetable(savTable, filePath, 'Delimiter', '\t');

                %mid
                midCentroid = spectralCentroid(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.midRange, SpectrumType=spectrumType);
                t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(midCentroid,1));
                nexttile;
                plot(t, midCentroid)
                title("Centroid - Mid Frequency Band " + app.midRange(1) + " to " + app.midRange(2) + "Hz - " + specTitle)
                xlabel('Time (s)')
                ylabel('Hertz (Hz)')
                xlim([0, t(end)]);

                filePath = fullfile(saveFolder, figureNames(figNum(1))+"_mid_"+".txt");
                savTable = array2table([t',midCentroid],"VariableNames",{'Time (s)', 'Frequency (Hz)'});
                writetable(savTable, filePath, 'Delimiter', '\t');

                %high
                highCentroid = spectralCentroid(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.highRange, SpectrumType=spectrumType);
                t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(highCentroid,1));
                nexttile;
                plot(t, highCentroid)
                title("Centroid - High Frequency Band " + app.highRange(1) + " to " + app.highRange(2) + "Hz - " + specTitle)
                xlabel('Time (s)')
                ylabel('Hertz (Hz)')
                xlim([0, t(end)]);

                filePath = fullfile(saveFolder, figureNames(figNum(1))+"_high_"+".txt");
                savTable = array2table([t',highCentroid],"VariableNames",{'Time (s)', 'Frequency (Hz)'});
                writetable(savTable, filePath, 'Delimiter', '\t');
    
                %% spread
                figList(figNum(2)) = figure(figNum(2));
                tiledlayout(2,2);
            
                %full
                fullSpread = spectralSpread(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.fullRange, SpectrumType=spectrumType);
                t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(fullSpread,1));
                nexttile;
                plot(t, fullSpread)
                title("Spread - Full Frequency Band " + app.fullRange(1) + " to " + app.fullRange(2) + "Hz - " + specTitle)
                xlabel('Time (s)')
                ylabel('Hertz (Hz)')
                xlim([0, t(end)]);

                filePath = fullfile(saveFolder, figureNames(figNum(2))+"_full_"+".txt");
                savTable = array2table([t',fullSpread],"VariableNames",{'Time (s)', 'Frequency (Hz)'});
                writetable(savTable, filePath, 'Delimiter', '\t');

                %low
                lowSpread = spectralSpread(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.lowRange, SpectrumType=spectrumType);
                t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(lowSpread,1));
                nexttile;
                plot(t, lowSpread)
                title("Spread - Low Frequency Band " + app.lowRange(1) + " to " + app.lowRange(2) + "Hz - " + specTitle)
                xlabel('Time (s)')
                ylabel('Hertz (Hz)')
                xlim([0, t(end)]);

                filePath = fullfile(saveFolder, figureNames(figNum(2))+"_low_"+".txt");
                savTable = array2table([t',lowSpread],"VariableNames",{'Time (s)', 'Frequency (Hz)'});
                writetable(savTable, filePath, 'Delimiter', '\t');
               
                %mid
                midSpread = spectralSpread(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.midRange, SpectrumType=spectrumType);
                t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(midSpread,1));
                nexttile;
                plot(t, midSpread)
                title("Spread - Mid Frequency Band " + app.midRange(1) + " to " + app.midRange(2) + "Hz - " + specTitle)
                xlabel('Time (s)')
                ylabel('Hertz (Hz)')
                xlim([0, t(end)]);

                filePath = fullfile(saveFolder, figureNames(figNum(2))+"_mid_"+".txt");
                savTable = array2table([t',midSpread],"VariableNames",{'Time (s)', 'Frequency (Hz)'});
                writetable(savTable, filePath, 'Delimiter', '\t');

                %high
                highSpread = spectralSpread(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.highRange, SpectrumType=spectrumType);
                t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(highSpread,1));
                nexttile
                plot(t, highSpread)
                title("Spread - High Frequency Band " + app.highRange(1) + " to " + app.highRange(2) + "Hz - " + specTitle)
                xlabel('Time (s)')
                ylabel('Hertz (Hz)')
                xlim([0, t(end)]);
    
                filePath = fullfile(saveFolder, figureNames(figNum(2))+"_high_"+".txt");
                savTable = array2table([t',highSpread],"VariableNames",{'Time (s)', 'Frequency (Hz)'});
                writetable(savTable, filePath, 'Delimiter', '\t');
         
                %% skewness
                figList(figNum(3)) = figure(figNum(3));
                tiledlayout(2,2);
    
                %full
                fullSkewness = spectralSkewness(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.fullRange, SpectrumType=spectrumType);
                t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(fullSkewness,1));
                nexttile;
                plot(t, fullSkewness)
                title("Skewness - Full Frequency Band " + app.fullRange(1) + " to " + app.fullRange(2) + "Hz - " + specTitle)
                xlabel('Time (s)')
                ylabel('Unitless')
                xlim([0, t(end)]);

                filePath = fullfile(saveFolder, figureNames(figNum(3))+"_full_"+".txt");
                savTable = array2table([t',fullSkewness],"VariableNames",{'Time (s)', 'Unitless'});
                writetable(savTable, filePath, 'Delimiter', '\t');

                %low
                lowSkewness = spectralSkewness(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.lowRange, SpectrumType=spectrumType);
                t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(lowSkewness,1));
                nexttile;
                plot(t, lowSkewness)
                title("Skewness - Low Frequency Band " + app.lowRange(1) + " to " + app.lowRange(2) + "Hz - " + specTitle)
                xlabel('Time (s)')
                ylabel('Unitless')
                xlim([0, t(end)]);

                filePath = fullfile(saveFolder, figureNames(figNum(3))+"_low_"+".txt");
                savTable = array2table([t',lowSkewness],"VariableNames",{'Time (s)', 'Unitless'});
                writetable(savTable, filePath, 'Delimiter', '\t');

                %mid
                midSkewness = spectralSkewness(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.midRange, SpectrumType=spectrumType);
                t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(midSkewness,1));
                nexttile;
                plot(t, midSkewness)
                title("Skewness - Mid Frequency Band " + app.midRange(1) + " to " + app.midRange(2) + "Hz - " + specTitle)
                xlabel('Time (s)')
                ylabel('Unitless')
                xlim([0, t(end)]);

                filePath = fullfile(saveFolder, figureNames(figNum(3))+"_mid_"+".txt");
                savTable = array2table([t',midSkewness],"VariableNames",{'Time (s)', 'Unitless'});
                writetable(savTable, filePath, 'Delimiter', '\t');

                %high
                highSkewness = spectralSkewness(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.highRange, SpectrumType=spectrumType);
                t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(highSkewness,1));
                nexttile;
                plot(t, highSkewness)
                title("Skewness - High Frequency Band " + app.highRange(1) + " to " + app.highRange(2) + "Hz - " + specTitle)
                xlabel('Time (s)')
                ylabel('Unitless')
                xlim([0, t(end)]);

                filePath = fullfile(saveFolder, figureNames(figNum(3))+"_high_"+".txt");
                savTable = array2table([t',highSkewness],"VariableNames",{'Time (s)', 'Unitless'});
                writetable(savTable, filePath, 'Delimiter', '\t');
                
                %% kurtosis
                figList(figNum(4)) = figure(figNum(4));
                tiledlayout(2,2);
                     
                %full
                fullKurtosis = spectralKurtosis(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.fullRange, SpectrumType=spectrumType);
                t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(fullKurtosis,1));
                nexttile;
                plot(t, fullKurtosis)
                title("Kurtosis - Full Frequency Band " + app.fullRange(1) + " to " + app.fullRange(2) + "Hz - " + specTitle)
                xlabel('Time (s)')
                ylabel('Unitless')
                xlim([0, t(end)]);

                filePath = fullfile(saveFolder, figureNames(figNum(4))+"_full_"+".txt");
                savTable = array2table([t',fullKurtosis],"VariableNames",{'Time (s)', 'Unitless'});
                writetable(savTable, filePath, 'Delimiter', '\t');

                %low
                lowKurtosis = spectralKurtosis(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.lowRange, SpectrumType=spectrumType);
                t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(lowKurtosis,1));
                nexttile;
                plot(t, lowKurtosis)
                title("Kurtosis - Low Frequency Band " + app.lowRange(1) + " to " + app.lowRange(2) + "Hz - " + specTitle)
                xlabel('Time (s)')
                ylabel('Unitless')
                xlim([0, t(end)]);

                filePath = fullfile(saveFolder, figureNames(figNum(4))+"_low_"+".txt");
                savTable = array2table([t',lowKurtosis],"VariableNames",{'Time (s)', 'Unitless'});
                writetable(savTable, filePath, 'Delimiter', '\t');

                %mid
                midKurtosis = spectralKurtosis(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.midRange, SpectrumType=spectrumType);
                t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(midKurtosis,1));
                nexttile;
                plot(t, midKurtosis)
                title("Kurtosis - Mid Frequency Band " + app.midRange(1) + " to " + app.midRange(2) + "Hz - " + specTitle)
                xlabel('Time (s)')
                ylabel('Unitless')
                xlim([0, t(end)]);

                filePath = fullfile(saveFolder, figureNames(figNum(4))+"_mid_"+".txt");
                savTable = array2table([t',midKurtosis],"VariableNames",{'Time (s)', 'Unitless'});
                writetable(savTable, filePath, 'Delimiter', '\t');

                %high
                highKurtosis = spectralKurtosis(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.highRange, SpectrumType=spectrumType);
                t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(highKurtosis,1));
                nexttile;
                plot(t, highKurtosis)
                title("Kurtosis - High Frequency Band " + app.highRange(1) + " to " + app.highRange(2) + "Hz - " + specTitle)
                xlabel('Time (s)')
                ylabel('Unitless')
                xlim([0, t(end)]);

                filePath = fullfile(saveFolder, figureNames(figNum(4))+"_high_"+".txt");
                savTable = array2table([t',highKurtosis],"VariableNames",{'Time (s)', 'Unitless'});
                writetable(savTable, filePath, 'Delimiter', '\t');

                %% entropy
                figList(figNum(5)) = figure(figNum(5));
                tiledlayout(2,2);
                
                %full
                fullEntropy = spectralEntropy(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.fullRange, SpectrumType=spectrumType);
                t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(fullEntropy,1));
                nexttile;
                plot(t, fullEntropy)
                title("Entropy - Full Frequency Band " + app.fullRange(1) + " to " + app.fullRange(2) + "Hz - " + specTitle)
                xlabel('Time (s)')
                ylabel('Nats')
                xlim([0, t(end)]);

                filePath = fullfile(saveFolder, figureNames(figNum(5))+"_full_"+".txt");
                savTable = array2table([t',fullEntropy],"VariableNames",{'Time (s)', 'Nats'});
                writetable(savTable, filePath, 'Delimiter', '\t');

                %low
                lowEntropy = spectralEntropy(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.lowRange, SpectrumType=spectrumType);
                t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(lowEntropy,1));
                nexttile;
                plot(t, lowEntropy)
                title("Entropy - Low Frequency Band "  + app.lowRange(1) + " to " + app.lowRange(2) + "Hz - " + specTitle)
                xlabel('Time (s)')
                ylabel('Nats')
                xlim([0, t(end)]);

                filePath = fullfile(saveFolder, figureNames(figNum(5))+"_low_"+".txt");
                savTable = array2table([t',lowEntropy],"VariableNames",{'Time (s)', 'Nats'});
                writetable(savTable, filePath, 'Delimiter', '\t');

                %mid
                midEntropy = spectralEntropy(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.midRange, SpectrumType=spectrumType);
                t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(midEntropy,1));
                nexttile;
                plot(t, midEntropy)
                title("Entropy - Mid Frequency Band " + app.midRange(1) + " to " + app.midRange(2) + "Hz - " + specTitle)
                xlabel('Time (s)')
                ylabel('Nats')
                xlim([0, t(end)]);

                filePath = fullfile(saveFolder, figureNames(figNum(5))+"_mid_"+".txt");
                savTable = array2table([t',midEntropy],"VariableNames",{'Time (s)', 'Nats'});
                writetable(savTable, filePath, 'Delimiter', '\t');

                %high
                highEntropy = spectralEntropy(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.highRange, SpectrumType=spectrumType);
                t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(highEntropy,1));
                nexttile;
                plot(t, highEntropy)
                title("Entropy - High Frequency Band " + app.highRange(1) + " to " + app.highRange(2) + "Hz - " + specTitle)
                xlabel('Time (s)')
                ylabel('Nats')
                xlim([0, t(end)]);

                filePath = fullfile(saveFolder, figureNames(figNum(5))+"_high_"+".txt");
                savTable = array2table([t',highEntropy],"VariableNames",{'Time (s)', 'Nats'});
                writetable(savTable, filePath, 'Delimiter', '\t');
    
                %% flatness
                figList(figNum(6)) = figure(figNum(6));
                tiledlayout(2,2);
                
                %full
                fullFlatness = spectralFlatness(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.fullRange, SpectrumType=spectrumType);
                t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(fullFlatness,1));
                nexttile;
                plot(t, fullFlatness)
                title("Flatness - Full Frequency Band " + app.fullRange(1) + " to " + app.fullRange(2) + "Hz - " + specTitle)
                xlabel('Time (s)')
                ylabel('Unitless')
                xlim([0, t(end)]);

                filePath = fullfile(saveFolder, figureNames(figNum(6))+"_full_"+".txt");
                savTable = array2table([t',fullFlatness],"VariableNames",{'Time (s)', 'Unitless'});
                writetable(savTable, filePath, 'Delimiter', '\t');

                %low
                lowFlatness = spectralFlatness(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.lowRange, SpectrumType=spectrumType);
                t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(lowFlatness,1));
                nexttile;
                plot(t, lowFlatness)
                title("Flatness - Low Frequency Band " + app.lowRange(1) + " to " + app.lowRange(2) + "Hz - " + specTitle)
                xlabel('Time (s)')
                ylabel('Unitless')
                xlim([0, t(end)]);

                filePath = fullfile(saveFolder, figureNames(figNum(6))+"_low_"+".txt");
                savTable = array2table([t',lowFlatness],"VariableNames",{'Time (s)', 'Unitless'});
                writetable(savTable, filePath, 'Delimiter', '\t');

                %mid
                midFlatness = spectralFlatness(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.midRange, SpectrumType=spectrumType);
                t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(midFlatness,1));
                nexttile;
                plot(t, midFlatness)
                title("Flatness - Mid Frequency Band " + app.midRange(1) + " to " + app.midRange(2) + "Hz - " + specTitle)
                xlabel('Time (s)')
                ylabel('Unitless')
                xlim([0, t(end)]);

                filePath = fullfile(saveFolder, figureNames(figNum(6))+"_mid_"+".txt");
                savTable = array2table([t',midFlatness],"VariableNames",{'Time (s)', 'Unitless'});
                writetable(savTable, filePath, 'Delimiter', '\t');

                %high
                highFlatness = spectralFlatness(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.highRange, SpectrumType=spectrumType);
                t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(highFlatness,1));
                nexttile;
                plot(t, highFlatness)
                title("Flatness - High Frequency Band " + app.highRange(1) + " to " + app.highRange(2) + "Hz - " + specTitle)
                xlabel('Time (s)')
                ylabel('Unitless')
                xlim([0, t(end)]);

                filePath = fullfile(saveFolder, figureNames(figNum(6))+"_high_"+".txt");
                savTable = array2table([t',highFlatness],"VariableNames",{'Time (s)', 'Unitless'});
                writetable(savTable, filePath, 'Delimiter', '\t');
                
                %% crest
                figList(figNum(7)) = figure(figNum(7));
                tiledlayout(2,2);
           
                %full
                fullCrest = spectralCrest(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.fullRange, SpectrumType=spectrumType);
                t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(fullCrest,1));
                nexttile;
                plot(t, fullCrest)
                title("Crest - Full Frequency Band " + app.fullRange(1) + " to " + app.fullRange(2) + "Hz - " + specTitle)
                xlabel('Time (s)')
                ylabel('Unitless')
                xlim([0, t(end)]);

                filePath = fullfile(saveFolder, figureNames(figNum(7))+"_full_"+".txt");
                savTable = array2table([t',fullCrest],"VariableNames",{'Time (s)', 'Unitless'});
                writetable(savTable, filePath, 'Delimiter', '\t');

                %low
                lowCrest = spectralCrest(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.lowRange, SpectrumType=spectrumType);
                t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(lowCrest,1));
                nexttile;
                plot(t, lowCrest)
                title("Crest - Low Frequency Band " + app.lowRange(1) + " to " + app.lowRange(2) + "Hz - " + specTitle)
                xlabel('Time (s)')
                ylabel('Unitless')
                xlim([0, t(end)]);

                filePath = fullfile(saveFolder, figureNames(figNum(7))+"_low_"+".txt");
                savTable = array2table([t',lowCrest],"VariableNames",{'Time (s)', 'Unitless'});
                writetable(savTable, filePath, 'Delimiter', '\t');

                %mid
                midCrest = spectralCrest(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.midRange, SpectrumType=spectrumType);
                t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(midCrest,1));
                nexttile;
                plot(t, midCrest)
                title("Crest - Mid Frequency Band " + app.midRange(1) + " to " + app.midRange(2) + "Hz - " + specTitle)
                xlabel('Time (s)')
                ylabel('Unitless')
                xlim( [0, t(end)]);

                filePath = fullfile(saveFolder, figureNames(figNum(7))+"_mid_"+".txt");
                savTable = array2table([t',midCrest],"VariableNames",{'Time (s)', 'Unitless'});
                writetable(savTable, filePath, 'Delimiter', '\t');

                %high
                highCrest = spectralCrest(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.highRange, SpectrumType=spectrumType);
                t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(highCrest,1));
                nexttile;
                plot(t, highCrest)
                title("Crest - High Frequency Band " + app.highRange(1) + " to " + app.highRange(2) + "Hz - " + specTitle)
                xlabel('Time (s)')
                ylabel('Unitless')
                xlim([0, t(end)]);

                filePath = fullfile(saveFolder, figureNames(figNum(7))+"_high_"+".txt");
                savTable = array2table([t',highCrest],"VariableNames",{'Time (s)', 'Unitless'});
                writetable(savTable, filePath, 'Delimiter', '\t');
                   
                %% flux
                figList(figNum(8)) = figure(figNum(8));
                tiledlayout(2,2);
    
                %full
                fullFlux = spectralFlux(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.fullRange, SpectrumType=spectrumType);
                t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(fullFlux,1));
                nexttile;
                plot(t, fullFlux)
                title("Flux - Full Frequency Band " + app.fullRange(1) + " to " + app.fullRange(2) + "Hz - " + specTitle)
                xlabel('Time (s)')
                ylabel('Unitless')
                xlim([0, t(end)]);

                filePath = fullfile(saveFolder, figureNames(figNum(8))+"_full_"+".txt");
                savTable = array2table([t',fullFlux],"VariableNames",{'Time (s)', 'Unitless'});
                writetable(savTable, filePath, 'Delimiter', '\t');

                %low
                lowFlux = spectralFlux(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.lowRange, SpectrumType=spectrumType);
                t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(lowFlux,1));
                nexttile;
                plot(t, lowFlux)
                title("Flux - Low Frequency Band " + app.lowRange(1) + " to " + app.lowRange(2) + "Hz - " + specTitle)
                xlabel('Time (s)')
                ylabel('Unitless')
                xlim([0, t(end)]);

                filePath = fullfile(saveFolder, figureNames(figNum(8))+"_low_"+".txt");
                savTable = array2table([t',lowFlux],"VariableNames",{'Time (s)', 'Unitless'});
                writetable(savTable, filePath, 'Delimiter', '\t');

                %mid
                midFlux = spectralFlux(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.midRange, SpectrumType=spectrumType);
                t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(midFlux,1));
                nexttile;
                plot(t, midFlux)
                title("Flux - Mid Frequency Band " + app.midRange(1) + " to " + app.midRange(2) + "Hz - " + specTitle)
                xlabel('Time (s)')
                ylabel('Unitless')
                xlim([0, t(end)]);

                filePath = fullfile(saveFolder, figureNames(figNum(8))+"_mid_"+".txt");
                savTable = array2table([t',midFlux],"VariableNames",{'Time (s)', 'Unitless'});
                writetable(savTable, filePath, 'Delimiter', '\t');

                %high
                highFlux = spectralFlux(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.highRange, SpectrumType=spectrumType);
                t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(highFlux,1));
                nexttile;
                plot(t, highFlux)
                title("Flux - High Frequency Band " + app.highRange(1) + " to " + app.highRange(2) + "Hz - " + specTitle)
                xlabel('Time (s)')
                ylabel('Unitless')
                xlim([0, t(end)]);
                
                filePath = fullfile(saveFolder, figureNames(figNum(8))+"_high_"+".txt");
                savTable = array2table([t',highFlux],"VariableNames",{'Time (s)', 'Unitless'});
                writetable(savTable, filePath, 'Delimiter', '\t');


                %% slope
                figList(figNum(9)) = figure(figNum(9));
                tiledlayout(2,2);
                
                %full
                fullSlope = spectralSlope(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.fullRange, SpectrumType=spectrumType);
                t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(fullSlope,1));
                nexttile;
                plot(t, fullSlope)
                title("Slope - Full Frequency Band " + app.fullRange(1) + " to " + app.fullRange(2) + "Hz - " + specTitle)
                xlabel('Time (s)')
                ylabel('Decibel/Hertz (dB/Hz)')
                xlim([0, t(end)]);

                filePath = fullfile(saveFolder, figureNames(figNum(9))+"_full_"+".txt");
                savTable = array2table([t',fullSlope],"VariableNames",{'Time (s)', 'Decibel/Hertz (dB/Hz)'});
                writetable(savTable, filePath, 'Delimiter', '\t');

                %low
                lowSlope = spectralSlope(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.lowRange, SpectrumType=spectrumType);
                t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(lowSlope,1));
                nexttile;
                plot(t, lowSlope)
                title("Slope - Low Frequency Band " + app.lowRange(1) + " to " + app.lowRange(2) + "Hz - " + specTitle)
                xlabel('Time (s)')
                ylabel('Decibel/Hertz (dB/Hz)')
                xlim([0, t(end)]);

                filePath = fullfile(saveFolder, figureNames(figNum(9))+"_low_"+".txt");
                savTable = array2table([t',lowSlope],"VariableNames",{'Time (s)', 'Decibel/Hertz (dB/Hz)'});
                writetable(savTable, filePath, 'Delimiter', '\t');

                %mid
                midSlope = spectralSlope(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.midRange, SpectrumType=spectrumType);
                t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(midSlope,1));
                nexttile;
                plot(t, midSlope)
                title("Slope - Mid Frequency Band " + app.midRange(1) + " to " + app.midRange(2) + "Hz - " + specTitle)
                xlabel('Time (s)')
                ylabel('Decibel/Hertz (dB/Hz)')
                xlim([0, t(end)]);

                filePath = fullfile(saveFolder, figureNames(figNum(9))+"_mid_"+".txt");
                savTable = array2table([t',midSlope],"VariableNames",{'Time (s)', 'Decibel/Hertz (dB/Hz)'});
                writetable(savTable, filePath, 'Delimiter', '\t');

                %high
                highSlope = spectralSlope(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.highRange, SpectrumType=spectrumType);
                t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(highSlope,1));
                nexttile;
                plot(t, highSlope)
                title("Slope - High Frequency Band " + app.highRange(1) + " to " + app.highRange(2) + "Hz - " + specTitle)
                xlabel('Time (s)')
                ylabel('Decibel/Hertz (dB/Hz)')
                xlim(app.HighFreq, [0, t(end)]);

                filePath = fullfile(saveFolder, figureNames(figNum(9))+"_high_"+".txt");
                savTable = array2table([t',highSlope],"VariableNames",{'Time (s)', 'Decibel/Hertz (dB/Hz)'});
                writetable(savTable, filePath, 'Delimiter', '\t');
    
                %% decrease
                figList(figNum(10)) = figure(figNum(10));
                tiledlayout(2,2);
                
                %full
                fullDecrease = spectralDecrease(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.fullRange, SpectrumType=spectrumType);
                t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(fullDecrease,1));
                nexttile;
                plot(t, fullDecrease)
                title("Decrease - Full Frequency Band " + app.fullRange(1) + " to " + app.fullRange(2) + "Hz - " + specTitle)
                xlabel('Time (s)')
                ylabel('Unitless')
                xlim([0, t(end)]);

                filePath = fullfile(saveFolder, figureNames(figNum(10))+"_full_"+".txt");
                savTable = array2table([t',fullDecrease],"VariableNames",{'Time (s)', 'Unitless'});
                writetable(savTable, filePath, 'Delimiter', '\t');

                %low
                lowDecrease = spectralDecrease(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.lowRange, SpectrumType=spectrumType);
                t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(lowDecrease,1));
                nexttile;
                plot(t, lowDecrease)
                title("Decrease - Low Frequency Band " + app.lowRange(1) + " to " + app.lowRange(2) + "Hz - " + specTitle)
                xlabel('Time (s)')
                ylabel('Unitless')
                xlim([0, t(end)]);

                filePath = fullfile(saveFolder, figureNames(figNum(10))+"_low_"+".txt");
                savTable = array2table([t',lowDecrease],"VariableNames",{'Time (s)', 'Unitless'});
                writetable(savTable, filePath, 'Delimiter', '\t');

                %mid
                midDecrease = spectralDecrease(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.midRange, SpectrumType=spectrumType);
                t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(midDecrease,1));
                nexttile;
                plot(t, midDecrease)
                title("Decrease - Mid Frequency Band " + app.midRange(1) + " to " + app.midRange(2) + "Hz - " + specTitle)
                xlabel('Time (s)')
                ylabel('Unitless')
                xlim([0, t(end)]);

                filePath = fullfile(saveFolder, figureNames(figNum(10))+"_mid_"+".txt");
                savTable = array2table([t',midDecrease],"VariableNames",{'Time (s)', 'Unitless'});
                writetable(savTable, filePath, 'Delimiter', '\t');

                %high
                highDecrease = spectralDecrease(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.highRange, SpectrumType=spectrumType);
                t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(highDecrease,1));
                nexttile;
                plot(t, highDecrease)
                title("Decrease - High Frequency Band " + app.highRange(1) + " to " + app.highRange(2) + "Hz - " + specTitle)
                xlabel('Time (s)')
                ylabel('Unitless')
                xlim([0, t(end)]);

                filePath = fullfile(saveFolder, figureNames(figNum(10))+"_high_"+".txt");
                savTable = array2table([t',highDecrease],"VariableNames",{'Time (s)', 'Unitless'});
                writetable(savTable, filePath, 'Delimiter', '\t');
               
                %% rolloff point
                figList(figNum(11)) = figure(figNum(11));
                tiledlayout(2,2);
                
                %full
                fullRolloffPoint = spectralRolloffPoint(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.fullRange, SpectrumType=spectrumType);
                t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(fullRolloffPoint,1));
                nexttile;
                plot(t, fullRolloffPoint)
                title("Rolloff Point - Full Frequency Band " + app.fullRange(1) + " to " + app.fullRange(2) + "Hz - " + specTitle)
                xlabel('Time (s)')
                ylabel('Hertz (Hz)')
                xlim([0, t(end)]);

                filePath = fullfile(saveFolder, figureNames(figNum(11))+"_full_"+".txt");
                savTable = array2table([t',fullRolloffPoint],"VariableNames",{'Time (s)', 'Hertz (Hz)'});
                writetable(savTable, filePath, 'Delimiter', '\t');

                %low
                lowRolloffPoint = spectralRolloffPoint(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.lowRange, SpectrumType=spectrumType);
                t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(lowRolloffPoint,1));
                nexttile;
                plot(t, lowRolloffPoint)
                title("Rolloff Point - Low Frequency Band " + app.lowRange(1) + " to " + app.lowRange(2) + "Hz - " + specTitle)
                xlabel('Time (s)')
                ylabel('Hertz (Hz)')
                xlim([0, t(end)]);

                filePath = fullfile(saveFolder, figureNames(figNum(11))+"_low_"+".txt");
                savTable = array2table([t',lowRolloffPoint],"VariableNames",{'Time (s)', 'Hertz (Hz)'});
                writetable(savTable, filePath, 'Delimiter', '\t');

                %mid
                midRolloffPoint = spectralRolloffPoint(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.midRange, SpectrumType=spectrumType);
                t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(midRolloffPoint,1));
                nexttile;
                plot(t, midRolloffPoint)
                title("Rolloff Point - Mid Frequency Band " + app.midRange(1) + " to " + app.midRange(2) + "Hz - " + specTitle)
                xlabel('Time (s)')
                ylabel('Hertz (Hz)')
                xlim([0, t(end)]);

                filePath = fullfile(saveFolder, figureNames(figNum(11))+"_mid_"+".txt");
                savTable = array2table([t',midRolloffPoint],"VariableNames",{'Time (s)', 'Hertz (Hz)'});
                writetable(savTable, filePath, 'Delimiter', '\t');

                %high
                highRolloffPoint = spectralRolloffPoint(app.data_soundPa,app.samplingRate,Window=app.win,OverlapLength=app.overlap,FFTLength=app.fftLength,Range=app.highRange, SpectrumType=spectrumType);
                t = linspace(0,size(app.data_soundPa,1)/app.samplingRate,size(highRolloffPoint,1));
                nexttile;
                plot(t, highRolloffPoint)
                title("Rolloff Point - High Frequency Band " + app.highRange(1) + " to " + app.highRange(2) + "Hz - " + specTitle)
                xlabel('Time (s)')
                ylabel('Hertz (Hz)')
                xlim([0, t(end)]);

                filePath = fullfile(saveFolder, figureNames(figNum(11))+"_high_"+".txt");
                savTable = array2table([t',highRolloffPoint],"VariableNames",{'Time (s)', 'Hertz (Hz)'});
                writetable(savTable, filePath, 'Delimiter', '\t');
                
            end
            
            for i = 1:length(figList)
                figHandle = figList(i);
                filePath = fullfile(saveFolder, figureNames(i)+".png");
                saveas(figHandle, filePath)
            end 

            app.UIFigure.Pointer = 'arrow';
            app.SaveFullAnalysisButton.Enable = 'on';
            app.SaveFullAnalysisButton.Text = 'Save Full Analysis';
            app.SaveFullAnalysisButton.BackgroundColor = [0.96,0.96,0.96]; 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HighFreq = uiaxes(app.UIFigure);
            title(app.HighFreq, 'High Frequency Band')
            xlabel(app.HighFreq, 'Time (s)')
            ylabel(app.HighFreq, 'Y')
            zlabel(app.HighFreq, 'Z')
            app.MidFreq = uiaxes(app.UIFigure);
            title(app.MidFreq, 'Mid Frequency Band')
            xlabel(app.MidFreq, 'Time (s)')
            ylabel(app.MidFreq, 'Y')
            zlabel(app.MidFreq, 'Z')
            app.LowFreq = uiaxes(app.UIFigure);
            title(app.LowFreq, 'Low Frequency Band')
            xlabel(app.LowFreq, 'Time (s)')
            ylabel(app.LowFreq, 'Y')
            zlabel(app.LowFreq, 'Z')
            app.FullFreq = uiaxes(app.UIFigure);
            title(app.FullFreq, 'Full Frequency Band')
            xlabel(app.FullFreq, 'Time (s)')
            ylabel(app.FullFreq, 'Y')
            zlabel(app.FullFreq, 'Z')
            app.TimeDomain = uiaxes(app.UIFigure);
            title(app.TimeDomain, 'Time-Domain Signal')
            xlabel(app.TimeDomain, 'Time (s)')
            ylabel(app.TimeDomain, 'Y')
            zlabel(app.TimeDomain, 'Z')
            app.STFT = uiaxes(app.UIFigure);
            title(app.STFT, 'STFT')
            xlabel(app.STFT, 'Time (s)')
            ylabel(app.STFT, 'Y')
            zlabel(app.STFT, 'Z')
            app.SignalandSamplingInformationPanel = uipanel(app.UIFigure);
            app.SignalandSamplingInformationPanel.Title = 'Signal and Sampling Information';
            app.SamplingRateHzEditFieldLabel = uilabel(app.SignalandSamplingInformationPanel);
            app.SamplingRateHzEditFieldLabel.Text = 'Sampling Rate (Hz)';
            app.SamplingRateHzEditField = uieditfield(app.SignalandSamplingInformationPanel, 'numeric');
            app.SamplingRateHzEditField.ValueDisplayFormat = '%.0f';
            app.SamplingRateHzEditField.Value = 51200;
            app.PathtoTDMSFileEditFieldLabel = uilabel(app.SignalandSamplingInformationPanel);
            app.PathtoTDMSFileEditFieldLabel.Text = 'Path to TDMS File';
            app.PathtoTDMSFileEditField = uieditfield(app.SignalandSamplingInformationPanel, 'text');
            app.PathtoTDMSFileEditField.Value = 'H:\LabVIEW 2015 Projects\ColdSprayMic\NozzleCloggingData\072326\off2on_D07_23_26_T15_28_51.tdms';
            app.AnalysisTypePanel = uipanel(app.UIFigure);
            app.AnalysisTypePanel.Title = 'Analysis Type';
            app.SpectrumTypeDropDownLabel = uilabel(app.AnalysisTypePanel);
            app.SpectrumTypeDropDownLabel.Text = 'Spectrum Type';
            app.SpectrumTypeDropDown = uidropdown(app.AnalysisTypePanel);
            app.SpectrumTypeDropDown.Items = {'Magnitude', 'Power', 'PSD'};
            app.SpectrumTypeDropDown.ItemsData = {'1', '2', '3'};
            app.SpectrumTypeDropDown.Value = '1';
            app.SpectralDescriptorDropDownLabel = uilabel(app.AnalysisTypePanel);
            app.SpectralDescriptorDropDownLabel.Text = 'Spectral Descriptor';
            app.SpectralDescriptorDropDown = uidropdown(app.AnalysisTypePanel);
            app.SpectralDescriptorDropDown.Items = {'Centroid', 'Spread', 'Skewness', 'Kurtosis', 'Entropy', 'Flatness', 'Crest', 'Flux', 'Slope', 'Decrease', 'Rolloff Point'};
            app.SpectralDescriptorDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11'};
            app.SpectralDescriptorDropDown.Value = '1';
            app.ShowDescriptorInformationButton = uibutton(app.AnalysisTypePanel, 'push');
            app.ShowDescriptorInformationButton.ButtonPushedFcn = createCallbackFcn(app, @ShowDescriptorInformationButtonPushed, true);
            app.ShowDescriptorInformationButton.Text = 'Show Descriptor Information';
            app.DescriptorInformation = uitextarea(app.AnalysisTypePanel);
            app.FramingControlsPanel = uipanel(app.UIFigure);
            app.FramingControlsPanel.Title = 'Framing Controls';
            app.WindowFunctionDropDownLabel = uilabel(app.FramingControlsPanel);
            app.WindowFunctionDropDownLabel.Text = 'Window Function';
            app.WindowFunctionDropDown = uidropdown(app.FramingControlsPanel);
            app.WindowFunctionDropDown.Items = {'Rectangular', 'Hann', 'Hamming', 'Blackman'};
            app.WindowFunctionDropDown.ItemsData = [1 2 3 4];
            app.WindowFunctionDropDown.Value = 1;
            app.WindowLengthmsEditFieldLabel = uilabel(app.FramingControlsPanel);
            app.WindowLengthmsEditFieldLabel.Text = 'Window Length (ms)';
            app.WindowLengthmsEditField = uieditfield(app.FramingControlsPanel, 'numeric');
            app.WindowLengthmsEditField.Value = 30;
            app.WindowOverlapmsEditFieldLabel = uilabel(app.FramingControlsPanel);
            app.WindowOverlapmsEditFieldLabel.Text = 'Window Overlap (ms)';
            app.WindowOverlapmsEditField = uieditfield(app.FramingControlsPanel, 'numeric');
            app.WindowOverlapmsEditField.Value = 20;
            app.Label = uilabel(app.FramingControlsPanel);
            app.Label.Text = 'Length must be greater than Overlap';
            app.SaveAnalysisandSettingPanel = uipanel(app.UIFigure);
            app.SaveAnalysisandSettingPanel.Title = 'Save Analysis and Setting';
            app.SaveAnalysisSettingOnlyButton = uibutton(app.SaveAnalysisandSettingPanel, 'push');
            app.SaveAnalysisSettingOnlyButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAnalysisSettingOnlyButtonPushed, true);
            app.SaveAnalysisSettingOnlyButton.Text = 'Save Analysis Setting Only';
            app.SaveFullAnalysisButton = uibutton(app.SaveAnalysisandSettingPanel, 'push');
            app.SaveFullAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFullAnalysisButtonPushed, true);
            app.SaveFullAnalysisButton.Text = 'Save Full Analysis';
            app.PathtoSaveFolderEditFieldLabel = uilabel(app.SaveAnalysisandSettingPanel);
            app.PathtoSaveFolderEditFieldLabel.Text = 'Path to Save Folder';
            app.PathtoSaveFolderEditField = uieditfield(app.SaveAnalysisandSettingPanel, 'text');
            app.PathtoSaveFolderEditField.Value = 'C:\Users\vbrachop\Desktop\testingresultssave';
            app.TextArea = uitextarea(app.SaveAnalysisandSettingPanel);
            app.TextArea.Value = {'For interpretation across descriptors, framing controls must be constant across descriptors. Select the framing controls that give the most interpretable analysis before saving.'};
            app.RunAnalysisButton = uibutton(app.UIFigure, 'push');
            app.RunAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisButtonPushed, true);
            app.RunAnalysisButton.Text = 'Run Analysis';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2_trial

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end