classdef CrossCorrelationApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        VERIFYALLINFORMATIONBEFORECLICKINGSTARTLabel  matlab.ui.control.Label
        StartDataProcessingButton       matlab.ui.control.Button
        TestingandProcessingInformationPanel  matlab.ui.container.Panel
        SamplingRateSsEditField         matlab.ui.control.EditField
        SamplingRateSsEditFieldLabel    matlab.ui.control.Label
        FrequencyHzEditField            matlab.ui.control.EditField
        FrequencyHzEditFieldLabel       matlab.ui.control.Label
        CycleOffsetDropDown             matlab.ui.control.DropDown
        CycleOffsetDropDownLabel        matlab.ui.control.Label
        TestStartTimePanel              matlab.ui.container.Panel
        SecsEditField                   matlab.ui.control.NumericEditField
        SecsEditFieldLabel              matlab.ui.control.Label
        MinsEditField                   matlab.ui.control.NumericEditField
        MinsEditFieldLabel              matlab.ui.control.Label
        Hr24EditField                   matlab.ui.control.NumericEditField
        Hr24EditFieldLabel              matlab.ui.control.Label
        YearEditField                   matlab.ui.control.NumericEditField
        YearEditFieldLabel              matlab.ui.control.Label
        DayEditField_2                  matlab.ui.control.NumericEditField
        DayEditField_2Label             matlab.ui.control.Label
        MonthEditField                  matlab.ui.control.NumericEditField
        MonthEditFieldLabel             matlab.ui.control.Label
        canbefoundintestingfoldernameLabel  matlab.ui.control.Label
        SampleInformationPanel          matlab.ui.container.Panel
        NumberofSamplesMAX4DropDown     matlab.ui.control.DropDown
        NumberofSamplesMAX4DropDownLabel  matlab.ui.control.Label
        ThicknessofSamplesmmPanel       matlab.ui.container.Panel
        ThicknessofSample4EditField     matlab.ui.control.NumericEditField
        ThicknessofSample4EditFieldLabel  matlab.ui.control.Label
        ThicknessofSample3EditField     matlab.ui.control.NumericEditField
        ThicknessofSample3EditFieldLabel  matlab.ui.control.Label
        ThicknessofSample2EditField     matlab.ui.control.NumericEditField
        ThicknessofSample2EditFieldLabel  matlab.ui.control.Label
        ThicknessofSample1EditField     matlab.ui.control.NumericEditField
        ThicknessofSample1EditFieldLabel  matlab.ui.control.Label
        leaveaszeroifsampledoesnotexistLabel  matlab.ui.control.Label
        FolderFilesInformationPanel     matlab.ui.container.Panel
        ResultsPanel                    matlab.ui.container.Panel
        ResultsFolderEditField          matlab.ui.control.EditField
        ResultsFolderEditFieldLabel     matlab.ui.control.Label
        CreatefoldertostoreresultsandinsertpathbelowLabel  matlab.ui.control.Label
        ExcitationDataPanel             matlab.ui.container.Panel
        ExcitationDataFileTypeDropDown  matlab.ui.control.DropDown
        ExcitationDataFileTypeDropDownLabel  matlab.ui.control.Label
        ExcitationFileEditField         matlab.ui.control.EditField
        ExcitationFileEditFieldLabel    matlab.ui.control.Label
        CollectedDataPanel              matlab.ui.container.Panel
        CollectedDataFileTypeDropDown   matlab.ui.control.DropDown
        CollectedDataFileTypeDropDownLabel  matlab.ui.control.Label
        CleanDataFolderEditField        matlab.ui.control.EditField
        CleanDataFolderEditFieldLabel   matlab.ui.control.Label
        RawDataFolderEditField          matlab.ui.control.EditField
        RawDataFolderEditFieldLabel     matlab.ui.control.Label
        CreatefoldertostorecleaneddataandinsertpathbelowLabel  matlab.ui.control.Label
        CrossCorrelationSignalProcessingforThroughTransmissionTestingLabel  matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartDataProcessingButton
        function StartDataProcessingButtonPushed(app, event)

            progDlg = uiprogressdlg(app.UIFigure, ...
                            'Title', 'Processing Data', ...
                            'Message', 'Cleaning Data...', ...
                            'Indeterminate', 'on', ...
                            'Cancelable', 'off');

            app.UIFigure.Pointer = 'watch';
            app.StartDataProcessingButton.Enable = 'off';        % Prevent double clicks
            app.StartDataProcessingButton.Text = 'Processing...'; % Visual feedback
            app.StartDataProcessingButton.BackgroundColor = [0.8 0.5 0.2]; % Orange accent
            
            % Force the UI to refresh immediately before the heavy computation starts!
            drawnow;

            sampleNumber = app.NumberofSamplesMAX4DropDown.Value;
            sampleOne = app.ThicknessofSample1EditField.Value;
            sampleTwo = app.ThicknessofSample2EditField.Value;
            sampleThree = app.ThicknessofSample3EditField.Value;
            sampleFour = app.ThicknessofSample4EditField.Value;
            sampleThickness = [sampleOne sampleTwo sampleThree sampleFour]; % sample thicknessess, ordered by increasing channel number (in millimeters) !!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!
            delTime = 3-0.03; % time delay to compensate for the oscilloscope clock (in hours)
            perThresh = 0.1; % percentage threshold multiplier (in this code
            % used to get the percentage of the maximum peak, and usually takes on values in the range 0.05-0.2)
                             % the higher this number, the less inclusive the threshold
            cycleOffset = app.CycleOffsetDropDown.Value; % integer number of cycles to offset from the central maximum peak !!!!!!!!!!!!!!!!!!!!!!!!
            samplingRate = str2double(app.SamplingRateSsEditField.Value); % sampling rate (in samples per second)
            frequency = str2double(app.FrequencyHzEditField.Value); %Hz
            month = app.MonthEditField.Value;
            day = app.DayEditField_2.Value;
            year = app.YearEditField.Value;
            hour = app.Hr24EditField.Value;
            minute = app.MinsEditField.Value;
            second = app.SecsEditField.Value;
            startTime = datenum(year,month,day,hour,minute,second); % start time serial date number (in days) 24hour time!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!
            dt = 1.0/samplingRate; % time step (in seconds)
            fileType = app.CollectedDataFileTypeDropDown.Value;
            fileType_ex = app.ExcitationDataFileTypeDropDown.Value;
            elementNum = cycleOffset*2 + 1; % total number of elements given by the desired number of cycle offsets (twice the number of
                                            % cycle offsets to account for the number of offset to the left and right of central peak, 
                                            % plus the one element at the central peak)
            curveNames = {'CH1','CH2','CH3','CH4'}; % container for names to dynamically assign to legend
            offset = 0.05; % how much away from the marker the text should be for the coordinates
           
            % Specify the folder where the files are located !!!!!!!!!!!!!!!!!!!!!!!!!!
            rawFolder = app.RawDataFolderEditField.Value;
            myFolder = app.CleanDataFolderEditField.Value;

            file_number_cleaning(rawFolder,myFolder,fileType,sampleNumber)

            % Check to make sure that folder actually exists.  Warn user if it doesn't
            if ~isfolder(myFolder)
                errorMessage = sprintf(['Error: The following folder does not ' ...
                    'exist:\n%s\nPlease specify a new folder.'], myFolder);
                uiwait(warndlg(errorMessage));
                myFolder = uigetdir(); % Ask for a new one
                if myFolder == 0
                     % User clicked Cancel
                     return;
                end
            end


            % !!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!
            % vector of amplitudes gathered from acquired samples
            baselineFile = app.ExcitationFileEditField.Value;
            switch fileType_ex
            case '.wfm'
                [baselineArray] = wfm3read(baselineFile);
            case '.isf'
                [baselineArray] = isfread(baselineFile);
            case '.csv'
                [baselineArray] = readmatrix(baselineFile);
            otherwise
                [baselineArray] = wfm3read(baselineFile);
            end

            for j = 1 : sampleNumber

                progDlg.Value   = j / sampleNumber; 
                progDlg.Message = sprintf('Processing Sample %d of %d...', j, sampleNumber);

                [leftBoundary, rightBoundary] = isf_analysis_region_selection_V2 (samplingRate, frequency, sampleThickness(1,j), fileType, j, myFolder, baselineArray);

                % get a list of all files in the folder with the desired file name pattern
                filePattern = fullfile(myFolder,['*_ch' int2str(j) '_*' fileType]); 
                s = dir(filePattern); % structure array containing attributes of files
            
                % 's' is the struct array. 'datenum' is the field that contains the
                % serial date time1
             
                T        = struct2table(s); % convert the struct array to a table
                sortedT  = sortrows(T,'datenum'); % sort the table by 'datenum'
                theFiles = table2struct(sortedT); % change it back to struct array
                
                n = 1; % initializing indexing varibale to keep track of the peak being followed (i.e. 1st, 2nd, 3rd, etc.)
                refPeaksIndexes = zeros(elementNum); % initializing vector to store the peak index locations
                bool = 0;
                
                while n <= length(refPeaksIndexes)
                    for k = length(theFiles):-1:1
                        leftCycleOffset = cycleOffset; % intializing variable with the number of cycles to offset to the left...
                                                       % sometimes the number of cycles to offset to the left are less than the amount that
                                                       % can be offset to the right (or even non-existent), but offsets to the right, along
                                                       % with the central maximum peak always exist
                    
                        % accesssing the name of the sample file in the structure
                        baseFileName = theFiles(k).name;
                        fullFileName = fullfile(theFiles(k).folder, baseFileName);
                        fprintf(1, 'Now reading %s\n', fullFileName);
                    
                        % vector of amplitudes gathered from acquired samples
                        switch fileType
                        case '.wfm'
                            [sampleArray] = wfm3read(fullFileName);
                        case '.isf'
                            [sampleArray] = isfread(fullFileName);
                        case '.csv'
                            [sampleArray] = readmatrix(fullFileName);
                        otherwise
                            [sampleArray] = wfm3read(fullFileName);
                        end
                        
                        if (k==length(theFiles)&n==1)
                            length(theFiles);
                        end
                        
                        sampleArray(1:round(leftBoundary(1))) = 0;
                        sampleArray(round(rightBoundary(1)):end) = 0;
                        
                   
                        % cross-corraltion vector of the input and output waveforms
                        crossCorr = cross_correlation(sampleArray,baselineArray);
                    
                        % maximum value in 'crossCorr' 
                        maximum = max(crossCorr);
                    
                        % find the peaks above a percent threshold of the maximum peak value
                        [pks,loc] = findpeaks(crossCorr,'MinPeakHeight',maximum*perThresh);
            
                        if n == 1
                            % vector containing the curing times (in days) converted to hours
                            cureTime(k) = (theFiles(k).datenum - startTime)*24 - delTime;
                            % optional feature: collect the amplitudes of the samples
                            amplitude(k,j) = max(sampleArray);
                        end
            
                        % initializing prevIndex variable to be used in the relative
                        % error calculation (relError)
                        if k == length(theFiles)
                            prevIndex = refPeaksIndexes(n);
                            prevError = 0; % initializing previous relative error variable to 
                                           % compare to the current relative error
                        end
                    
                        if bool == 0
                            % index where the maximum value of obtained peaks subset occurs
                            maxSubsetIndex = find(pks==maximum);
                    
                            % counting the number of 'NaN' values to append to the left side of the final 'time of flight' vector
                            while((maxSubsetIndex - leftCycleOffset) < 1)
                                leftCycleOffset = leftCycleOffset - 1;
                            end
                    
                            % establish the first set of reference peak indexes to use as benchmark to
                            % make up to <elementNum> running plots of the same sample (offset at each peak)
                            refPeaksIndexes = loc(maxSubsetIndex-leftCycleOffset:maxSubsetIndex+cycleOffset);
            
                            bool = 1;
            
                            break
                        end
                    
                        % take the relative error between previous time indexes and current ones
                        for i = 1:length(loc)
                            % relative percent error between the previous index position for the time of flight associated with the old highest peak, and the new one 
                            relError = abs((loc(i)-prevIndex)/prevIndex)*100;
                            
                            if relError <= prevError
                                selectedIndex = loc(i);
                            end
            
                            prevError = relError;
                        end
                    
                        flightTime = selectedIndex*dt;
                        flightTime2 (k,j,n) = flightTime*1e6; 
                        prevIndex = selectedIndex;
                    
                        % vector containing the speeds of sound through the sample (mm/us)
                        % sample thickness divided by the flight time converted to microseconds
                        speed(k,j,n) = sampleThickness(j)/(flightTime*1e6); 
                    end
            
                    if k ~= length(theFiles)
                        % plot the speed versus the curing time
                        figure(j)
                        plot(cureTime,speed(:,j,n));
                        n = n + 1;
                        hold on;
                    end
            
                end
                
                figure(j)
                hold on;
                % label the plot
                legend;
                xlabel("Cure Time (hr)");
                ylabel("Speed (mm/us)");


            end

            close(progDlg);

            figure(sampleNumber + 1)
            hold on;
            
            % table to store final results (cureTime, speeds, and amplitudes)
            results = table(cureTime',zeros(length(theFiles),sampleNumber),amplitude);
            results2 = table(cureTime', flightTime2);
            temp1 = zeros(length(theFiles),sampleNumber); % initializing temporary array to store speeds
            
            figure(1);
            drawnow;
            for a = 1 : sampleNumber
                promptText = sprintf(['Please indicate which curve you would like to use ' ...
                    'from the figure(s) by typing the corresponding number (e.g., ' ...
                    'if the 3rd curve (data3) is needed type in the number 3 and ' ...
                    'press enter). Curve for sample %d, figure %d:'], a, a);
                
                prompt = {promptText};
                dlgtitle = sprintf('Select Curve for Sample %d', a);
                definput = {'1'};
                field_size = [1,100];
                figure(a);
                drawnow;
                answer = inputdlg(prompt,dlgtitle,field_size,definput);
                answer = str2double(answer{1});
            
                if isempty(answer)
                    break; %stops loop if user hits cancel
                end
            
                figure(sampleNumber + 1)
                plot(cureTime,speed(:,a,answer));
                legend(curveNames(1:a));
            
                temp1(:,a) = speed(:,a,answer);
            end
            
            results{:,2} = temp1;
            results = renamevars(results,["Var1","Var2","amplitude"],["Cure Time (hr)","Speed (mm/us)","Magnitude (V)"]);
            
            hold off;
            % label the plot
            xlabel("Cure Time (hr)");
            ylabel("Speed (mm/us)");
            
            resultsFolder = app.ResultsFolderEditField.Value;
            filePath = fullfile(resultsFolder, 'Results.csv');

            writetable(results, filePath);

            app.UIFigure.Pointer = 'arrow';
            app.StartDataProcessingButton.Enable = 'on';
            app.StartDataProcessingButton.Text = 'Start Data Processing';
            app.StartDataProcessingButton.BackgroundColor = [0.2314 0.6667 0.1961]; % Original green


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CrossCorrelationSignalProcessingforThroughTransmissionTestingLabel = uilabel(app.UIFigure);
            app.CrossCorrelationSignalProcessingforThroughTransmissionTestingLabel.Text = 'Cross-Correlation Signal Processing for Through Transmission Testing';
            app.FolderFilesInformationPanel = uipanel(app.UIFigure);
            app.FolderFilesInformationPanel.Title = 'Folder/Files Information';
            app.CollectedDataPanel = uipanel(app.FolderFilesInformationPanel);
            app.CollectedDataPanel.Title = 'Collected Data';
            app.CreatefoldertostorecleaneddataandinsertpathbelowLabel = uilabel(app.CollectedDataPanel);
            app.CreatefoldertostorecleaneddataandinsertpathbelowLabel.Text = 'Create folder to store cleaned data and insert path below:';
            app.RawDataFolderEditFieldLabel = uilabel(app.CollectedDataPanel);
            app.RawDataFolderEditFieldLabel.Text = 'Raw Data Folder';
            app.RawDataFolderEditField = uieditfield(app.CollectedDataPanel, 'text');
            app.RawDataFolderEditField.Value = 'H:\041626\testing_ss_md032_ch1sample_ch2exc_D04_16_26_T10_54_01';
            app.CleanDataFolderEditFieldLabel = uilabel(app.CollectedDataPanel);
            app.CleanDataFolderEditFieldLabel.Text = 'Clean Data Folder';
            app.CleanDataFolderEditField = uieditfield(app.CollectedDataPanel, 'text');
            app.CleanDataFolderEditField.Value = 'H:\041626\cleantestingfolder';
            app.CollectedDataFileTypeDropDownLabel = uilabel(app.CollectedDataPanel);
            app.CollectedDataFileTypeDropDownLabel.Text = 'Collected Data File Type';
            app.CollectedDataFileTypeDropDown = uidropdown(app.CollectedDataPanel);
            app.CollectedDataFileTypeDropDown.Items = {'.isf', '.wfm'};
            app.CollectedDataFileTypeDropDown.ItemsData = {'.isf', '.wfm'};
            app.CollectedDataFileTypeDropDown.Value = '.isf';
            app.ExcitationDataPanel = uipanel(app.FolderFilesInformationPanel);
            app.ExcitationDataPanel.Title = 'Excitation Data';
            app.ExcitationFileEditFieldLabel = uilabel(app.ExcitationDataPanel);
            app.ExcitationFileEditFieldLabel.Text = 'Excitation File';
            app.ExcitationFileEditField = uieditfield(app.ExcitationDataPanel, 'text');
            app.ExcitationFileEditField.Value = 'H:\041626\testing_ss_md032_ch1sample_ch2exc_D04_16_26_T10_54_01\waveform_ch2_1.isf';
            app.ExcitationDataFileTypeDropDownLabel = uilabel(app.ExcitationDataPanel);
            app.ExcitationDataFileTypeDropDownLabel.Text = 'Excitation Data File Type';
            app.ExcitationDataFileTypeDropDown = uidropdown(app.ExcitationDataPanel);
            app.ExcitationDataFileTypeDropDown.Items = {'.isf', '.wfm'};
            app.ExcitationDataFileTypeDropDown.ItemsData = {'.isf', '.wfm'};
            app.ExcitationDataFileTypeDropDown.Value = '.isf';
            app.ResultsPanel = uipanel(app.FolderFilesInformationPanel);
            app.ResultsPanel.Title = 'Results';
            app.CreatefoldertostoreresultsandinsertpathbelowLabel = uilabel(app.ResultsPanel);
            app.CreatefoldertostoreresultsandinsertpathbelowLabel.Text = 'Create folder to store results and insert path below:';
            app.ResultsFolderEditFieldLabel = uilabel(app.ResultsPanel);
            app.ResultsFolderEditFieldLabel.Text = 'Results Folder';
            app.ResultsFolderEditField = uieditfield(app.ResultsPanel, 'text');
            app.ResultsFolderEditField.Value = 'H:\041626\resultstestingfolder';
            app.SampleInformationPanel = uipanel(app.UIFigure);
            app.SampleInformationPanel.Title = 'Sample Information';
            app.ThicknessofSamplesmmPanel = uipanel(app.SampleInformationPanel);
            app.ThicknessofSamplesmmPanel.Title = 'Thickness of Samples (mm)';
            app.leaveaszeroifsampledoesnotexistLabel = uilabel(app.ThicknessofSamplesmmPanel);
            app.leaveaszeroifsampledoesnotexistLabel.Text = '(leave as zero if sample does not exist)';
            app.ThicknessofSample1EditFieldLabel = uilabel(app.ThicknessofSamplesmmPanel);
            app.ThicknessofSample1EditFieldLabel.Text = 'Thickness of Sample 1';
            app.ThicknessofSample1EditField = uieditfield(app.ThicknessofSamplesmmPanel, 'numeric');
            app.ThicknessofSample1EditField.Value = 6;
            app.ThicknessofSample2EditFieldLabel = uilabel(app.ThicknessofSamplesmmPanel);
            app.ThicknessofSample2EditFieldLabel.Text = 'Thickness of Sample 2';
            app.ThicknessofSample2EditField = uieditfield(app.ThicknessofSamplesmmPanel, 'numeric');
            app.ThicknessofSample3EditFieldLabel = uilabel(app.ThicknessofSamplesmmPanel);
            app.ThicknessofSample3EditFieldLabel.Text = 'Thickness of Sample 3';
            app.ThicknessofSample3EditField = uieditfield(app.ThicknessofSamplesmmPanel, 'numeric');
            app.ThicknessofSample4EditFieldLabel = uilabel(app.ThicknessofSamplesmmPanel);
            app.ThicknessofSample4EditFieldLabel.Text = 'Thickness of Sample 4';
            app.ThicknessofSample4EditField = uieditfield(app.ThicknessofSamplesmmPanel, 'numeric');
            app.NumberofSamplesMAX4DropDownLabel = uilabel(app.SampleInformationPanel);
            app.NumberofSamplesMAX4DropDownLabel.Text = 'Number of Samples (MAX 4)';
            app.NumberofSamplesMAX4DropDown = uidropdown(app.SampleInformationPanel);
            app.NumberofSamplesMAX4DropDown.Items = {'1', '2', '3', '4'};
            app.NumberofSamplesMAX4DropDown.ItemsData = [1 2 3 4];
            app.NumberofSamplesMAX4DropDown.Value = 1;
            app.TestingandProcessingInformationPanel = uipanel(app.UIFigure);
            app.TestingandProcessingInformationPanel.Title = 'Testing and Processing Information';
            app.TestStartTimePanel = uipanel(app.TestingandProcessingInformationPanel);
            app.TestStartTimePanel.Title = 'Test Start Time';
            app.canbefoundintestingfoldernameLabel = uilabel(app.TestStartTimePanel);
            app.canbefoundintestingfoldernameLabel.Text = '(can be found in testing folder name)';
            app.MonthEditFieldLabel = uilabel(app.TestStartTimePanel);
            app.MonthEditFieldLabel.Text = 'Month';
            app.MonthEditField = uieditfield(app.TestStartTimePanel, 'numeric');
            app.MonthEditField.Value = 4;
            app.DayEditField_2Label = uilabel(app.TestStartTimePanel);
            app.DayEditField_2Label.Text = 'Day';
            app.DayEditField_2 = uieditfield(app.TestStartTimePanel, 'numeric');
            app.DayEditField_2.Value = 10;
            app.YearEditFieldLabel = uilabel(app.TestStartTimePanel);
            app.YearEditFieldLabel.Text = 'Year';
            app.YearEditField = uieditfield(app.TestStartTimePanel, 'numeric');
            app.YearEditField.Value = 2026;
            app.Hr24EditFieldLabel = uilabel(app.TestStartTimePanel);
            app.Hr24EditFieldLabel.Text = 'Hr (24)';
            app.Hr24EditField = uieditfield(app.TestStartTimePanel, 'numeric');
            app.Hr24EditField.Value = 10;
            app.MinsEditFieldLabel = uilabel(app.TestStartTimePanel);
            app.MinsEditFieldLabel.Text = 'Mins';
            app.MinsEditField = uieditfield(app.TestStartTimePanel, 'numeric');
            app.MinsEditField.Value = 54;
            app.SecsEditFieldLabel = uilabel(app.TestStartTimePanel);
            app.SecsEditFieldLabel.Text = 'Secs';
            app.SecsEditField = uieditfield(app.TestStartTimePanel, 'numeric');
            app.SecsEditField.Value = 1;
            app.CycleOffsetDropDownLabel = uilabel(app.TestingandProcessingInformationPanel);
            app.CycleOffsetDropDownLabel.Text = 'Cycle Offset';
            app.CycleOffsetDropDown = uidropdown(app.TestingandProcessingInformationPanel);
            app.CycleOffsetDropDown.Items = {'0', '1', '2'};
            app.CycleOffsetDropDown.ItemsData = [0 1 2];
            app.CycleOffsetDropDown.Value = 0;
            app.FrequencyHzEditFieldLabel = uilabel(app.TestingandProcessingInformationPanel);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency (Hz)';
            app.FrequencyHzEditField = uieditfield(app.TestingandProcessingInformationPanel, 'text');
            app.FrequencyHzEditField.Value = '4e6';
            app.SamplingRateSsEditFieldLabel = uilabel(app.TestingandProcessingInformationPanel);
            app.SamplingRateSsEditFieldLabel.Text = 'Sampling Rate (S/s)';
            app.SamplingRateSsEditField = uieditfield(app.TestingandProcessingInformationPanel, 'text');
            app.SamplingRateSsEditField.Value = '2.5e9';
            app.StartDataProcessingButton = uibutton(app.UIFigure, 'push');
            app.StartDataProcessingButton.ButtonPushedFcn = createCallbackFcn(app, @StartDataProcessingButtonPushed, true);
            app.StartDataProcessingButton.Text = 'Start Data Processing';
            app.VERIFYALLINFORMATIONBEFORECLICKINGSTARTLabel = uilabel(app.UIFigure);
            app.VERIFYALLINFORMATIONBEFORECLICKINGSTARTLabel.Text = 'VERIFY ALL INFORMATION BEFORE CLICKING START';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CrossCorrelationApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end